classdef dset_viewer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        autoregCheckBox                 matlab.ui.control.CheckBox
        automatCheckBox                 matlab.ui.control.CheckBox
        regiontoplotDropDown            matlab.ui.control.DropDown
        regiontoplotDropDownLabel       matlab.ui.control.Label
        plotcolorlegendsButton          matlab.ui.control.Button
        UpdateButton                    matlab.ui.control.Button
        RespthreshEditField             matlab.ui.control.NumericEditField
        RespthreshEditFieldLabel        matlab.ui.control.Label
        ResponsivecellsselectDropDown   matlab.ui.control.DropDown
        ResponsivecellsselectDropDownLabel  matlab.ui.control.Label
        CloseallButton                  matlab.ui.control.Button
        ResposivecellstypeDropDown      matlab.ui.control.DropDown
        ResposivecellstypeDropDownLabel  matlab.ui.control.Label
        ContoursDropDown                matlab.ui.control.DropDown
        ContoursDropDownLabel           matlab.ui.control.Label
        SelectdatagroupDropDown         matlab.ui.control.DropDown
        SelectdatagroupDropDownLabel    matlab.ui.control.Label
        StimtimesButton                 matlab.ui.control.StateButton
        trialtypeDropDown               matlab.ui.control.DropDown
        trialtypeDropDownLabel          matlab.ui.control.Label
        SpikesButton                    matlab.ui.control.StateButton
        CButton                         matlab.ui.control.StateButton
        RawButton                       matlab.ui.control.StateButton
        ExperimentDropDown              matlab.ui.control.DropDown
        ExperimentDropDownLabel         matlab.ui.control.Label
        ViewparamsButton                matlab.ui.control.Button
        OverwriteCheckBox               matlab.ui.control.CheckBox
        anchordsetSpinner               matlab.ui.control.Spinner
        anchordsetSpinnerLabel          matlab.ui.control.Label
        SavematdataButton               matlab.ui.control.Button
        LoadmatdataButton               matlab.ui.control.Button
        matdatapathEditField            matlab.ui.control.EditField
        matdatapathEditFieldLabel       matlab.ui.control.Label
        RunallDropDown                  matlab.ui.control.DropDown
        RunallDropDownLabel             matlab.ui.control.Label
        RunallButton                    matlab.ui.control.Button
        TabGroup                        matlab.ui.container.TabGroup
        OpsTab                          matlab.ui.container.Tab
        BarplottypeDropDown             matlab.ui.control.DropDown
        BarplottypeDropDownLabel        matlab.ui.control.Label
        BarstatstypeDropDown            matlab.ui.control.DropDown
        BarstatstypeDropDownLabel       matlab.ui.control.Label
        shadowon3dCheckBox              matlab.ui.control.CheckBox
        gridon3dCheckBox                matlab.ui.control.CheckBox
        FigrenderpaintersCheckBox       matlab.ui.control.CheckBox
        nanhandlemetDropDown            matlab.ui.control.DropDown
        nanhandlemetDropDownLabel       matlab.ui.control.Label
        InvertcmapCheckBox              matlab.ui.control.CheckBox
        normalizeeucCheckBox            matlab.ui.control.CheckBox
        dosimilarityCheckBox            matlab.ui.control.CheckBox
        DistreferenceDropDown           matlab.ui.control.DropDown
        DistreferenceDropDownLabel      matlab.ui.control.Label
        DimredmethodDropDown            matlab.ui.control.DropDown
        DimredmethodDropDownLabel       matlab.ui.control.Label
        DistmethodDropDown              matlab.ui.control.DropDown
        DistmethodDropDownLabel         matlab.ui.control.Label
        MattridataplotDropDown          matlab.ui.control.DropDown
        MattridataplotDropDownLabel     matlab.ui.control.Label
        ColormapDropDown                matlab.ui.control.DropDown
        ColormapDropDownLabel           matlab.ui.control.Label
        StimtimesPanel                  matlab.ui.container.Panel
        ShiftEditField_st               matlab.ui.control.NumericEditField
        ShiftEditField_3Label           matlab.ui.control.Label
        ScaleEditField_st               matlab.ui.control.NumericEditField
        ScaleEditField_3Label           matlab.ui.control.Label
        VolttracePanel                  matlab.ui.container.Panel
        ShiftEditField_volt             matlab.ui.control.NumericEditField
        ShiftEditField_3Label_2         matlab.ui.control.Label
        ScaleEditField_volt             matlab.ui.control.NumericEditField
        ScaleEditField_3Label_2         matlab.ui.control.Label
        VoltchanDropDown                matlab.ui.control.DropDown
        VoltchanDropDownLabel           matlab.ui.control.Label
        VolttraceButton                 matlab.ui.control.StateButton
        SpikesPanel                     matlab.ui.container.Panel
        ShiftEditField_sp               matlab.ui.control.NumericEditField
        ShiftEditFieldLabel             matlab.ui.control.Label
        ScaleEditField_sp               matlab.ui.control.NumericEditField
        ScaleEditFieldLabel             matlab.ui.control.Label
        SmoothCheckBox                  matlab.ui.control.CheckBox
        SmoothsigmamsEditField          matlab.ui.control.NumericEditField
        SmoothsigmamsEditFieldLabel     matlab.ui.control.Label
        DeconvolutionmethodDropDown     matlab.ui.control.DropDown
        DeconvolutionmethodDropDownLabel  matlab.ui.control.Label
        singlecellTab                   matlab.ui.container.Tab
        normpeakwithinCheckBox          matlab.ui.control.CheckBox
        PlotnontunedCheckBox            matlab.ui.control.CheckBox
        AreabordersCheckBox             matlab.ui.control.CheckBox
        WhitebkgCheckBox                matlab.ui.control.CheckBox
        plotdsetstatsButton             matlab.ui.control.Button
        somecellplotsButton             matlab.ui.control.Button
        plotwfconvButton                matlab.ui.control.Button
        plotwftuningButton              matlab.ui.control.Button
        PlottuningButton                matlab.ui.control.Button
        PhaseplotCheckBox               matlab.ui.control.CheckBox
        plotensamblesCheckBox           matlab.ui.control.CheckBox
        plotfractionsCheckBox           matlab.ui.control.CheckBox
        ParadigmEditField               matlab.ui.control.EditField
        ParadigmEditFieldLabel          matlab.ui.control.Label
        analysis_BaserespwinEditField   matlab.ui.control.EditField
        AnalysisbaserespwinsecLabel     matlab.ui.control.Label
        plot_BaserespwinEditField       matlab.ui.control.EditField
        PlotbaserespwinsecLabel         matlab.ui.control.Label
        poolregionsCheckBox             matlab.ui.control.CheckBox
        UseregdatalabelsCheckBox        matlab.ui.control.CheckBox
        ColoredbytimeCheckBox           matlab.ui.control.CheckBox
        IndividualtrialsCheckBox        matlab.ui.control.CheckBox
        ConverttoZCheckBox              matlab.ui.control.CheckBox
        numfreqSpinner                  matlab.ui.control.Spinner
        numfreqSpinnerLabel             matlab.ui.control.Label
        SizefactorEditField             matlab.ui.control.NumericEditField
        SizefactorEditFieldLabel        matlab.ui.control.Label
        SizemagadjustCheckBox           matlab.ui.control.CheckBox
        PlotregButton                   matlab.ui.control.Button
        FramerateEditField              matlab.ui.control.NumericEditField
        FramerateEditFieldLabel         matlab.ui.control.Label
        MMNfreqEditField                matlab.ui.control.EditField
        MMNfreqEditFieldLabel           matlab.ui.control.Label
        SNREditField                    matlab.ui.control.NumericEditField
        SNREditFieldLabel               matlab.ui.control.Label
        PlotselecttrialButton           matlab.ui.control.Button
        NewplotsCheckBox                matlab.ui.control.CheckBox
        FullredCheckBox                 matlab.ui.control.CheckBox
        PlotctxrespButton               matlab.ui.control.Button
        PlotfreqrespButton              matlab.ui.control.Button
        singlecell2Tab                  matlab.ui.container.Tab
        DecodertuningButton             matlab.ui.control.Button
        plotbarButton                   matlab.ui.control.Button
        winanalyzeDropDown              matlab.ui.control.DropDown
        winanalyzeDropDownLabel         matlab.ui.control.Label
        minYlimEditField                matlab.ui.control.NumericEditField
        minYlimEditFieldLabel           matlab.ui.control.Label
        maxYlimEditField                matlab.ui.control.NumericEditField
        maxYlimEditFieldLabel           matlab.ui.control.Label
        kdesmfactorEditField            matlab.ui.control.NumericEditField
        kdesmfactorEditFieldLabel       matlab.ui.control.Label
        moretuningplotsButton           matlab.ui.control.Button
        PlotstimCheckBox                matlab.ui.control.CheckBox
        StimtranspEditField             matlab.ui.control.NumericEditField
        StimtranspEditFieldLabel        matlab.ui.control.Label
        stimcolorSpinner                matlab.ui.control.Spinner
        stimcolorSpinnerLabel           matlab.ui.control.Label
        plotPCAdimSpinner               matlab.ui.control.Spinner
        plotPCAdimSpinnerLabel          matlab.ui.control.Label
        popavefreqButton                matlab.ui.control.Button
        plottypeDropDown                matlab.ui.control.DropDown
        plottypeDropDownLabel           matlab.ui.control.Label
        plotaxesDropDown                matlab.ui.control.DropDown
        plotaxesDropDownLabel           matlab.ui.control.Label
        statsbetweenDropDown            matlab.ui.control.DropDown
        statsbetweenDropDownLabel       matlab.ui.control.Label
        yvarDropDown                    matlab.ui.control.DropDown
        yvarDropDownLabel               matlab.ui.control.Label
        xvarDropDown                    matlab.ui.control.DropDown
        xvarDropDownLabel               matlab.ui.control.Label
        plotsingletrialPCAButton        matlab.ui.control.Button
        PCAdistancesCheckBox            matlab.ui.control.CheckBox
        plotstatsCheckBox               matlab.ui.control.CheckBox
        plotsuperdeetsCheckBox          matlab.ui.control.CheckBox
        plotpopmmnButton                matlab.ui.control.Button
        continteractionplotsButton      matlab.ui.control.Button
        plotdistbyareaButton            matlab.ui.control.Button
        plottrialavePCAButton           matlab.ui.control.Button
        plotCDRButton                   matlab.ui.control.Button
        plotxyButton                    matlab.ui.control.Button
        plotfeatureDropDown             matlab.ui.control.DropDown
        plotfeatureDropDownLabel        matlab.ui.control.Label
        MarginalizedistCheckBox         matlab.ui.control.CheckBox
        plotMMNButton                   matlab.ui.control.Button
        plotdistButton                  matlab.ui.control.Button
        PlottuningdistButton            matlab.ui.control.Button
        statsTab                        matlab.ui.container.Tab
        MvRegressionButton              matlab.ui.control.Button
        statswithinButton               matlab.ui.control.Button
        NewstatsparamsPanel             matlab.ui.container.Panel
        stats_MiddleRespwinEditField    matlab.ui.control.EditField
        MiddlerespwindowLabel           matlab.ui.control.Label
        stats_OffsetRespwinEditField    matlab.ui.control.EditField
        OffsetrespwindowLabel           matlab.ui.control.Label
        stats_OnsetRespwinEditField     matlab.ui.control.EditField
        OnsetrespwindowLabel            matlab.ui.control.Label
        stats_LimSigRespwinEditField    matlab.ui.control.EditField
        LimsigrespwindowLabel           matlab.ui.control.Label
        stats_StatmethodDropDown        matlab.ui.control.DropDown
        stats_StatsourceDropDown        matlab.ui.control.DropDown
        stats_ZthreshEditField          matlab.ui.control.NumericEditField
        stats_PvalEditField             matlab.ui.control.NumericEditField
        stats_PeakbintimesecEditField   matlab.ui.control.NumericEditField
        stats_NumshuffsampEditField     matlab.ui.control.NumericEditField
        stats_BaserespwinEditField      matlab.ui.control.EditField
        stats_LocothreshEditField       matlab.ui.control.NumericEditField
        recomputestatsButton            matlab.ui.control.Button
        BaserespwindowEditFieldLabel    matlab.ui.control.Label
        LocothreshEditFieldLabel_2      matlab.ui.control.Label
        NumshuffsampEditFieldLabel      matlab.ui.control.Label
        PeakbintimesecEditFieldLabel_2  matlab.ui.control.Label
        PvalEditFieldLabel_2            matlab.ui.control.Label
        ZthreshEditFieldLabel_2         matlab.ui.control.Label
        StatsourceDropDownLabel         matlab.ui.control.Label
        StatmethodDropDownLabel         matlab.ui.control.Label
        computecdataButton              matlab.ui.control.Button
        RectifyspikesCheckBox           matlab.ui.control.CheckBox
        SubtractmeanspikesCheckBox      matlab.ui.control.CheckBox
        NormalizemaxspikesCheckBox      matlab.ui.control.CheckBox
        ensestimateTab                  matlab.ui.container.Tab
        dimestCVparamsPanel             matlab.ui.container.Panel
        PlotdimCVButton                 matlab.ui.control.Button
        DimCVEditField                  matlab.ui.control.NumericEditField
        EstimatedimCVButton             matlab.ui.control.Button
        dimestcv_normalizeDropDown      matlab.ui.control.DropDown
        dimestcv_ensmethodDropDown      matlab.ui.control.DropDown
        dimestcv_shuffledatachunksCheckBox  matlab.ui.control.CheckBox
        dimestcv_sm_centerEditField     matlab.ui.control.NumericEditField
        dimestcv_sm_rangeEditField      matlab.ui.control.NumericEditField
        dimestcv_sm_countEditField      matlab.ui.control.NumericEditField
        dimestcv_smoothSDwindowEditField  matlab.ui.control.EditField
        dimestcv_nc_centerEditField     matlab.ui.control.NumericEditField
        dimestcv_nc_rangeEditField      matlab.ui.control.NumericEditField
        dimestcv_nc_countEditField      matlab.ui.control.NumericEditField
        dimestcv_centeratdimpcaCheckBox  matlab.ui.control.CheckBox
        dimestcv_numcompwindowEditField  matlab.ui.control.EditField
        dimestcv_repsEditField          matlab.ui.control.NumericEditField
        dimestcv_includeshuffversionCheckBox  matlab.ui.control.CheckBox
        rangeEditField_4Label           matlab.ui.control.Label
        countEditField_2Label           matlab.ui.control.Label
        centerEditField_4Label          matlab.ui.control.Label
        rangeEditField_3Label           matlab.ui.control.Label
        countEditFieldLabel             matlab.ui.control.Label
        centerEditField_3Label          matlab.ui.control.Label
        DimCVEditFieldLabel             matlab.ui.control.Label
        numcompwindowEditFieldLabel     matlab.ui.control.Label
        repsEditFieldLabel              matlab.ui.control.Label
        smoothSDwindowEditFieldLabel    matlab.ui.control.Label
        normalizeDropDownLabel_3        matlab.ui.control.Label
        ensmethodDropDownLabel          matlab.ui.control.Label
        dimestpcaparamsPanel            matlab.ui.container.Panel
        DimpcaEditField                 matlab.ui.control.NumericEditField
        EstimatedimpcaButton            matlab.ui.control.Button
        dimestpca_normalizeDropDown     matlab.ui.control.DropDown
        dimestpca_shufflemethodDropDown  matlab.ui.control.DropDown
        dimestpca_totaldimthreshEditField  matlab.ui.control.NumericEditField
        dimestpca_dimestnumrepsEditField  matlab.ui.control.NumericEditField
        dimestpca_PlotstuffCheckBox     matlab.ui.control.CheckBox
        DimpcaEditFieldLabel            matlab.ui.control.Label
        dimestnumrepsEditFieldLabel_2   matlab.ui.control.Label
        totaldimthreshEditField_2Label  matlab.ui.control.Label
        shufflemethodDropDownLabel      matlab.ui.control.Label
        normalizeDropDownLabel          matlab.ui.control.Label
        ensfindTab                      matlab.ui.container.Tab
        ensonlysignificantCheckBox      matlab.ui.control.CheckBox
        numenstofindfracEditField       matlab.ui.control.NumericEditField
        numenstofindfracEditFieldLabel  matlab.ui.control.Label
        numenstofindmethodDropDown      matlab.ui.control.DropDown
        numenstofindmethodDropDownLabel  matlab.ui.control.Label
        ensembleplot2Button             matlab.ui.control.Button
        ensshuffrepsEditField           matlab.ui.control.NumericEditField
        shuffrepsEditFieldLabel         matlab.ui.control.Label
        ensembleplotButton              matlab.ui.control.Button
        ensemblestatsButton             matlab.ui.control.Button
        numsigensEditField              matlab.ui.control.NumericEditField
        numsigensEditFieldLabel         matlab.ui.control.Label
        numenstofindvalEditField        matlab.ui.control.NumericEditField
        numenstofindvalEditFieldLabel   matlab.ui.control.Label
        ensembleanalysisButton          matlab.ui.control.Button
        newensembleparamsPanel          matlab.ui.container.Panel
        ens_extrasmoothSDEditField      matlab.ui.control.NumericEditField
        extrasmoothSDEditFieldLabel     matlab.ui.control.Label
        ens_ensemethodDropDown          matlab.ui.control.DropDown
        ens_normalizeDropDown           matlab.ui.control.DropDown
        ens_ensextractionDropDown       matlab.ui.control.DropDown
        ens_extractionthreshDropDown    matlab.ui.control.DropDown
        ens_signalzthreshEditField      matlab.ui.control.NumericEditField
        ens_shuffthreshprcEditField     matlab.ui.control.NumericEditField
        ens_hclustmethodDropDown        matlab.ui.control.DropDown
        ens_hclustmetricDropDown        matlab.ui.control.DropDown
        ens_plotstuffCheckBox           matlab.ui.control.CheckBox
        hclustmetricDropDownLabel       matlab.ui.control.Label
        hclustmethodDropDownLabel       matlab.ui.control.Label
        shuffthreshprcEditFieldLabel    matlab.ui.control.Label
        signalzthreshEditFieldLabel     matlab.ui.control.Label
        extractionthreshDropDownLabel   matlab.ui.control.Label
        ensextractionDropDownLabel      matlab.ui.control.Label
        normalizeDropDownLabel_2        matlab.ui.control.Label
        ensmethodLabel                  matlab.ui.control.Label
        ensplotsTab                     matlab.ui.container.Tab
        similarityonevoneButton         matlab.ui.control.Button
        decoderonevoneButton            matlab.ui.control.Button
        sampnumEditField                matlab.ui.control.NumericEditField
        sampnumEditFieldLabel           matlab.ui.control.Label
        samprangeintervEditField        matlab.ui.control.NumericEditField
        samprangeintervEditFieldLabel   matlab.ui.control.Label
        samprangemaxEditField           matlab.ui.control.NumericEditField
        samprangemaxEditFieldLabel      matlab.ui.control.Label
        samprangeminEditField           matlab.ui.control.NumericEditField
        samprangeminEditFieldLabel      matlab.ui.control.Label
        plotprevtrialCheckBox           matlab.ui.control.CheckBox
        sortprevtrialCheckBox           matlab.ui.control.CheckBox
        sortcellbytraceaveCheckBox      matlab.ui.control.CheckBox
        plotpopvecCheckBox              matlab.ui.control.CheckBox
        EqualizetrialsampsizeCheckBox   matlab.ui.control.CheckBox
        CorrtypeDropDown                matlab.ui.control.DropDown
        CorrtypeDropDownLabel           matlab.ui.control.Label
        SorttypeDropDown                matlab.ui.control.DropDown
        SorttypeDropDownLabel           matlab.ui.control.Label
        decoderonevallButton            matlab.ui.control.Button
        enslesscorrsampcellsButton      matlab.ui.control.Button
        enslesscorr2Button              matlab.ui.control.Button
        enslesscorrButton               matlab.ui.control.Button
        Enslesssingletrial2Button       matlab.ui.control.Button
        plotensrasterButton             matlab.ui.control.Button
        clusttriggeredwhatDropDown      matlab.ui.control.DropDown
        clusttriggeredwhatDropDownLabel  matlab.ui.control.Label
        ClustertouseEditField           matlab.ui.control.NumericEditField
        ClustertouseEditFieldLabel      matlab.ui.control.Label
        plotclusttriggeredstuffCheckBox  matlab.ui.control.CheckBox
        NumclustEditField               matlab.ui.control.NumericEditField
        NumclustEditFieldLabel          matlab.ui.control.Label
        HclusterCheckBox                matlab.ui.control.CheckBox
        PlotrespensCheckBox             matlab.ui.control.CheckBox
        PlotcombinedtrialsCheckBox      matlab.ui.control.CheckBox
        PlotcorrtotalsCheckBox          matlab.ui.control.CheckBox
        SelectcellsDropDown             matlab.ui.control.DropDown
        SelectcellsDropDownLabel        matlab.ui.control.Label
        ShufletrorderinsteadCheckBox    matlab.ui.control.CheckBox
        PlotindivtrialsCheckBox         matlab.ui.control.CheckBox
        EnslesstrialdimButton           matlab.ui.control.Button
        sortwithfullfrCheckBox          matlab.ui.control.CheckBox
        sorttrialsCheckBox              matlab.ui.control.CheckBox
        resortbyensCheckBox             matlab.ui.control.CheckBox
        selectrespcellsCheckBox         matlab.ui.control.CheckBox
        enslesssingletrialButton        matlab.ui.control.Button
        plotensraster2Button            matlab.ui.control.Button
        SortenstrialsCheckBox           matlab.ui.control.CheckBox
        plotscoresrasterButton          matlab.ui.control.Button
        plotensdeetsButton              matlab.ui.control.Button
        plotenscountsButton             matlab.ui.control.Button
        plotsortingstuffCheckBox        matlab.ui.control.CheckBox
        sortbytrialtypeCheckBox         matlab.ui.control.CheckBox
        sortbycellsimilarityCheckBox    matlab.ui.control.CheckBox
        shuffletrialsCheckBox           matlab.ui.control.CheckBox
        shufflecellsCheckBox            matlab.ui.control.CheckBox
        plotrasterButton                matlab.ui.control.Button
        ensplots2Tab                    matlab.ui.container.Tab
        ReverseZCheckBox                matlab.ui.control.CheckBox
        ReverseYCheckBox                matlab.ui.control.CheckBox
        ReverseXCheckBox                matlab.ui.control.CheckBox
        FlipshadowZCheckBox             matlab.ui.control.CheckBox
        FlipshadowYCheckBox             matlab.ui.control.CheckBox
        FlipshadowXCheckBox             matlab.ui.control.CheckBox
        NumplotaxesDropDown             matlab.ui.control.DropDown
        NumplotaxesDropDownLabel        matlab.ui.control.Label
        projectsingletrialsCheckBox     matlab.ui.control.CheckBox
        plotaxesCheckBox                matlab.ui.control.CheckBox
        equalizeaxesCheckBox            matlab.ui.control.CheckBox
        numcompplotSpinner              matlab.ui.control.Spinner
        numcompplotSpinnerLabel         matlab.ui.control.Label
        scalebyvarCheckBox              matlab.ui.control.CheckBox
        plotsubmeanCheckBox             matlab.ui.control.CheckBox
        subtractmeanCheckBox            matlab.ui.control.CheckBox
        distancesButton                 matlab.ui.control.Button
        lowdimprojcell2Button           matlab.ui.control.Button
        lowdimprojtrial2Button          matlab.ui.control.Button
        dimesttrialshuffButton          matlab.ui.control.Button
        dsetplotindivtrialsButton       matlab.ui.control.Button
        lowdimprojcellctxButton         matlab.ui.control.Button
        LDcontpadEditField              matlab.ui.control.NumericEditField
        LDcontpadEditFieldLabel         matlab.ui.control.Label
        lowdimprojfulldataButton        matlab.ui.control.Button
        lowdimprojtrialctxButton        matlab.ui.control.Button
        isomap2Button                   matlab.ui.control.Button
        lowdimprojtrialButton           matlab.ui.control.Button
        lowdimprojcellButton            matlab.ui.control.Button
        roiregTab                       matlab.ui.container.Tab
        MMNacrossdsetsButton            matlab.ui.control.Button
        tuningacrossdsetsButton         matlab.ui.control.Button
        NumdsetstoplotEditField         matlab.ui.control.EditField
        NumdsetstoplotEditFieldLabel    matlab.ui.control.Label
        regscoretreshEditField          matlab.ui.control.NumericEditField
        regscoretreshEditFieldLabel     matlab.ui.control.Label
        regdatatouseDropDown            matlab.ui.control.DropDown
        regdatatouseDropDownLabel       matlab.ui.control.Label
        loadcaimanroiregistractionButton  matlab.ui.control.Button
        plotregroisButton               matlab.ui.control.Button
        RvalueEditField                 matlab.ui.control.NumericEditField
        RvalueEditFieldLabel            matlab.ui.control.Label
        CNNprobEditField                matlab.ui.control.NumericEditField
        CNNprobEditFieldLabel           matlab.ui.control.Label
        SNR2EditField                   matlab.ui.control.NumericEditField
        SNR2EditFieldLabel              matlab.ui.control.Label
        SNRcaimanEditField              matlab.ui.control.NumericEditField
        SNRcaimanEditFieldLabel         matlab.ui.control.Label
        registerroisButton              matlab.ui.control.Button
        UIAxes_roi                      matlab.ui.control.UIAxes
        spontTab                        matlab.ui.container.Tab
        plotoptoresponseButton          matlab.ui.control.Button
        plotoptosignalcorrButton        matlab.ui.control.Button
        plotoptospontcorrButton         matlab.ui.control.Button
        plotoptotuningButton            matlab.ui.control.Button
        AddoptocellsButton              matlab.ui.control.Button
        plotrasterspontButton           matlab.ui.control.Button
        optodataTab                     matlab.ui.container.Tab
        TFwithinzthreshEditField        matlab.ui.control.NumericEditField
        TFwithinzthreshEditFieldLabel   matlab.ui.control.Label
        licktuningsimilarityButton      matlab.ui.control.Button
        PlotlickresponseButton          matlab.ui.control.Button
        PlottuningframewithinButton     matlab.ui.control.Button
        computetuningsimilarityButton   matlab.ui.control.Button
        plottuningthreshEditField       matlab.ui.control.NumericEditField
        plottuningthreshEditFieldLabel  matlab.ui.control.Label
        ScalewithmagCheckBox            matlab.ui.control.CheckBox
        PlottuningtypeDropDown          matlab.ui.control.DropDown
        PlottuningtypeDropDownLabel     matlab.ui.control.Label
        PlottuningframeButton           matlab.ui.control.Button
        UpdatefigsCheckBox              matlab.ui.control.CheckBox
        LoadregdataButton               matlab.ui.control.Button
        regdatapathEditField            matlab.ui.control.EditField
        regdatapathEditFieldLabel       matlab.ui.control.Label
        CellSpinner                     matlab.ui.control.Spinner
        CellSpinnerLabel                matlab.ui.control.Label
        mplSpinner                      matlab.ui.control.Spinner
        mplSpinnerLabel                 matlab.ui.control.Label
        DatasetDropDown                 matlab.ui.control.DropDown
        DatasetDropDownLabel            matlab.ui.control.Label
        Runs31Button                    matlab.ui.control.Button
        ContourlimitsLabel              matlab.ui.control.Label
        ContourMaxEditField             matlab.ui.control.NumericEditField
        MaxLabel                        matlab.ui.control.Label
        ContourMinEditField             matlab.ui.control.NumericEditField
        MinLabel                        matlab.ui.control.Label
        UIAxesColorPallet               matlab.ui.control.UIAxes
        UIAxes2                         matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
    end


    properties (Access = public)
        gui_ops = struct;
        data;
        ops;
        ddata;
        cdata;
        reg_data;
        working_ops;
        gui_plots;
        current_data_idx;
        current_cell_raw;
        current_cell_C;
        current_cell_spikes;
        border_coords;
        cp_app;
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.gui_ops.gui_dir = fileparts(which('dset_viewer.mlapp'));
            addpath(app.gui_ops.gui_dir);
            addpath([app.gui_ops.gui_dir '\gui_functions']);
            addpath([app.gui_ops.gui_dir '\general_functions']);
            addpath([app.gui_ops.gui_dir '\s3_mpl_functions']);
            f_dset_viewer_ops(app);
            f_dv_initialize(app);
        end

        % Button pushed function: Runs31Button
        function Runs31ButtonPushed(app, event)
            f_dv_runs31_button(app);
        end

        % Value changed function: DatasetDropDown
        function DatasetDropDownValueChanged(app, event)
            f_dv_load_dset_from_data(app);
        end

        % Value changed function: mplSpinner
        function mplSpinnerValueChanged(app, event)
            f_dv_update_dset_info(app);
            f_dv_update_A(app);
            f_dv_update_cell(app);
            f_dv_initialize_contours(app);
            f_dv_set_contorus(app);
        end

        % Value changed function: CellSpinner
        function CellSpinnerValueChanged(app, event)
            f_dv_update_cell(app);
        end

        % Button pushed function: LoadregdataButton
        function LoadregdataButtonPushed(app, event)
            f_dv_load_reg_data(app);
        end

        % Value changed function: RawButton
        function RawButtonValueChanged(app, event)
            f_dv_update_cell(app);
        end

        % Value changed function: CButton
        function CButtonValueChanged(app, event)
            f_dv_update_cell(app);
        end

        % Value changed function: SpikesButton
        function SpikesButtonValueChanged(app, event)
            f_dv_update_cell(app);
        end

        % Value changed function: StimtimesButton
        function StimtimesButtonValueChanged(app, event)
            f_dv_update_cell(app);
        end

        % Value changed function: trialtypeDropDown
        function trialtypeDropDownValueChanged(app, event)
            f_dv_update_cell(app);
            if app.UpdatefigsCheckBox.Value
                if isgraphics(app.gui_plots.select_resp_fig)
                    f_dv_plot_select_trial(app)
                end
            end
            f_dv_set_contorus(app);
        end

        % Button pushed function: UpdateButton
        function UpdateButtonPushed(app, event)
            f_dv_update_cell(app);
            f_dv_set_contorus(app)
        end

        % Button pushed function: PlotfreqrespButton
        function PlotfreqrespButtonPushed(app, event)
            f_dv_plot_freq_resp(app);
        end

        % Button pushed function: PlotctxrespButton
        function PlotctxrespButtonPushed(app, event)
            f_dv_plot_ctx_resp(app);
        end

        % Button pushed function: PlotselecttrialButton
        function PlotselecttrialButtonPushed(app, event)
            f_dv_plot_select_trial(app);
        end

        % Value changed function: SmoothCheckBox
        function SmoothCheckBoxValueChanged(app, event)
            f_dv_update_current_cdata(app);
            f_dv_update_cell(app);
        end

        % Value changed function: DeconvolutionmethodDropDown
        function DeconvolutionmethodDropDownValueChanged(app, event)
            f_dv_update_current_cdata(app);
            f_dv_update_cell(app);
        end

        % Value changed function: stats_StatsourceDropDown
        function stats_StatsourceDropDownValueChanged(app, event)
            f_dv_update_dset_info(app); 
        end

        % Value changed function: stats_StatmethodDropDown
        function stats_StatmethodDropDownValueChanged(app, event)
            f_dv_update_dset_info(app);
        end

        % Button pushed function: recomputestatsButton
        function recomputestatsButtonPushed(app, event)
            f_dv_compute_stats(app);
        end

        % Value changed function: ContourMinEditField
        function ContourMinEditFieldValueChanged(app, event)
            f_dv_contour_val_changed(app);
        end

        % Value changed function: ContourMaxEditField
        function ContourMaxEditFieldValueChanged(app, event)
            f_dv_contour_val_changed(app);
        end

        % Value changed function: RectifyspikesCheckBox
        function RectifyspikesCheckBoxValueChanged(app, event)
            f_dv_update_current_cdata(app);
            f_dv_update_cell(app);
            f_dv_set_contorus(app);
        end

        % Value changed function: SubtractmeanspikesCheckBox
        function SubtractmeanspikesCheckBoxValueChanged(app, event)
            f_dv_update_current_cdata(app);
            f_dv_update_cell(app);
            f_dv_set_contorus(app);
        end

        % Value changed function: NormalizemaxspikesCheckBox
        function NormalizemaxspikesCheckBoxValueChanged(app, event)
            f_dv_update_current_cdata(app);
            f_dv_update_cell(app);
            f_dv_set_contorus(app);
        end

        % Value changed function: ConverttoZCheckBox
        function ConverttoZCheckBoxValueChanged(app, event)
            f_dv_update_cell(app);
            f_dv_set_contorus(app);
        end

        % Button pushed function: PlotregButton
        function PlotregButtonPushed(app, event)
            f_dv_plot_wf_reg(app);
        end

        % Button pushed function: PlottuningdistButton
        function PlottuningdistButtonPushed(app, event)
            f_dv_plot_tuning_dist(app);
        end

        % Value changed function: numfreqSpinner
        function numfreqSpinnerValueChanged(app, event)
            f_dv_plot_reg(app);
        end

        % Button pushed function: plotdistButton
        function plotdistButtonPushed(app, event)
            f_dv_plot_dist(app);
        end

        % Button pushed function: plotMMNButton
        function plotMMNButtonPushed(app, event)
            f_dv_plot_mmn(app);
        end

        % Button pushed function: plotrasterButton
        function plotrasterButtonPushed(app, event)
            f_dv_plot_raster(app);
        end

        % Button pushed function: ensembleanalysisButton
        function ensembleanalysisButtonPushed(app, event)
            f_dv_ensemble_extract(app);
        end

        % Button pushed function: EstimatedimpcaButton
        function EstimatedimpcaButtonPushed(app, event)
            f_dv_estimate_dim_pca(app);
        end

        % Button pushed function: EstimatedimCVButton
        function EstimatedimCVButtonPushed(app, event)
            f_dv_estimate_dim_cv(app);
        end

        % Button pushed function: RunallButton
        function RunallButtonPushed(app, event)
            f_dv_run_all(app);
        end

        % Button pushed function: LoadmatdataButton
        function LoadmatdataButtonPushed(app, event)
            f_dv_load_mat_data(app);
        end

        % Button pushed function: SavematdataButton
        function SavematdataButtonPushed(app, event)
            f_dv_save_mat_data(app);
        end

        % Button pushed function: computecdataButton
        function computecdataButtonPushed(app, event)
            f_dv_compute_cdata_all(app);
        end

        % Button pushed function: plotenscountsButton
        function plotenscountsButtonPushed(app, event)
            f_dv_plot_ens_counts(app);
        end

        % Button pushed function: plotensdeetsButton
        function plotensdeetsButtonPushed(app, event)
            f_dv_plot_ens_deets(app);
        end

        % Button pushed function: plotscoresrasterButton
        function plotscoresrasterButtonPushed(app, event)
            f_dv_plot_scores_raster(app);
        end

        % Button pushed function: plotensraster2Button
        function plotensraster2ButtonPushed(app, event)
            f_dv_plot_ens_raster2(app);
        end

        % Button pushed function: enslesssingletrialButton
        function enslesssingletrialButtonPushed(app, event)
            f_dv_ensless_single_trial(app);
        end

        % Button pushed function: EnslesstrialdimButton
        function EnslesstrialdimButtonPushed(app, event)
            f_dv_ensless_trial_dim(app);
        end

        % Value changed function: stats_ZthreshEditField
        function stats_ZthreshEditFieldValueChanged(app, event)
            app.stats_PvalEditField.Value = (1-normcdf(app.stats_ZthreshEditField.Value));
        end

        % Value changed function: stats_PvalEditField
        function stats_PvalEditFieldValueChanged(app, event)
            app.stats_ZthreshEditField.Value = norminv(1-app.stats_PvalEditField.Value);
        end

        % Button pushed function: plotensrasterButton
        function plotensrasterButtonPushed(app, event)
            f_dv_plot_ens_raster(app);
        end

        % Button pushed function: Enslesssingletrial2Button
        function Enslesssingletrial2ButtonPushed(app, event)
            f_dv_ensless_single_trial2(app);
        end

        % Button pushed function: enslesscorrButton
        function enslesscorrButtonPushed(app, event)
            f_dv_ensless_single_trial_corr(app);
        end

        % Button pushed function: ViewparamsButton
        function ViewparamsButtonPushed(app, event)
            app.cp_app = current_params(app);
            f_dv_update_params(app);
        end

        % Value changed function: dimestcv_sm_centerEditField
        function dimestcv_sm_centerEditFieldValueChanged(app, event)
            app.dimestcv_smoothSDwindowEditField.Value = num2str(f_dv_get_dim_est_cv_sm(app));
        end

        % Value changed function: dimestcv_sm_rangeEditField
        function dimestcv_sm_rangeEditFieldValueChanged(app, event)
            app.dimestcv_smoothSDwindowEditField.Value = num2str(f_dv_get_dim_est_cv_sm(app));
        end

        % Value changed function: dimestcv_sm_countEditField
        function dimestcv_sm_countEditFieldValueChanged(app, event)
            app.dimestcv_smoothSDwindowEditField.Value = num2str(f_dv_get_dim_est_cv_sm(app));
        end

        % Value changed function: dimestcv_nc_centerEditField
        function dimestcv_nc_centerEditFieldValueChanged(app, event)
            app.dimestcv_numcompwindowEditField.Value = num2str(f_dv_get_dim_est_cv_nc(app));
        end

        % Value changed function: dimestcv_nc_rangeEditField
        function dimestcv_nc_rangeEditFieldValueChanged(app, event)
            app.dimestcv_numcompwindowEditField.Value = num2str(f_dv_get_dim_est_cv_nc(app));
        end

        % Value changed function: dimestcv_nc_countEditField
        function dimestcv_nc_countEditFieldValueChanged(app, event)
            app.dimestcv_numcompwindowEditField.Value = num2str(f_dv_get_dim_est_cv_nc(app));
        end

        % Value changed function: dimestcv_centeratdimpcaCheckBox
        function dimestcv_centeratdimpcaCheckBoxValueChanged(app, event)
            app.dimestcv_numcompwindowEditField.Value = num2str(f_dv_get_dim_est_cv_nc(app));
        end

        % Button pushed function: registerroisButton
        function registerroisButtonPushed(app, event)
            f_dv_register_roi_button(app);
        end

        % Button pushed function: plotregroisButton
        function plotregroisButtonPushed(app, event)
            f_dv_plot_reg_rois(app);
        end

        % Button pushed function: statswithinButton
        function statswithinButtonPushed(app, event)
            f_dv_stats_within(app);
        end

        % Button pushed function: plotrasterspontButton
        function plotrasterspontButtonPushed(app, event)
            f_dv_plot_raster_spont(app);
        end

        % Button pushed function: ensemblestatsButton
        function ensemblestatsButtonPushed(app, event)
            f_dv_ensemble_stats(app);
        end

        % Button pushed function: ensembleplotButton
        function ensembleplotButtonPushed(app, event)
            f_dv_ensemble_plot(app);
        end

        % Button pushed function: PlottuningframeButton
        function PlottuningframeButtonPushed(app, event)
            f_dv_plot_tuning_frame(app);
        end

        % Button pushed function: computetuningsimilarityButton
        function computetuningsimilarityButtonPushed(app, event)
            f_dv_comp_tuning_similarity(app);
        end

        % Button pushed function: PlottuningframewithinButton
        function PlottuningframewithinButtonPushed(app, event)
            f_dv_plot_tuning_frame_within(app);
        end

        % Button pushed function: PlotlickresponseButton
        function PlotlickresponseButtonPushed(app, event)
            f_dv_plot_lick_response(app);
        end

        % Button pushed function: licktuningsimilarityButton
        function licktuningsimilarityButtonPushed(app, event)
            f_dv_lick_tuning_similarity(app);
        end

        % Button pushed function: PlotdimCVButton
        function PlotdimCVButtonPushed(app, event)
            f_dv_plot_dim_cv(app);
        end

        % Button pushed function: ensembleplot2Button
        function ensembleplot2ButtonPushed(app, event)
            f_dv_ensemble_plot2(app);
        end

        % Button pushed function: enslesscorr2Button
        function enslesscorr2ButtonPushed(app, event)
            f_dv_ensless_single_trial_corr2(app);
        end

        % Button pushed function: plotxyButton
        function plotxyButtonPushed(app, event)
            f_dv_plot_xy(app);
        end

        % Button pushed function: plotCDRButton
        function plotCDRButtonPushed(app, event)
            f_dv_plot_cont_dev_red(app);
        end

        % Button pushed function: loadcaimanroiregistractionButton
        function loadcaimanroiregistractionButtonPushed(app, event)
            f_dv_load_caiman_roi_reg(app);
        end

        % Button pushed function: AddoptocellsButton
        function AddoptocellsButtonPushed(app, event)
            f_dv_add_opto_cells(app);
        end

        % Button pushed function: plotoptotuningButton
        function plotoptotuningButtonPushed(app, event)
            f_dv_opto_plot_tuning(app);
        end

        % Button pushed function: plotoptospontcorrButton
        function plotoptospontcorrButtonPushed(app, event)
            f_dv_opto_plot_spont_corr(app);
        end

        % Button pushed function: plotoptosignalcorrButton
        function plotoptosignalcorrButtonPushed(app, event)
            f_dv_opto_plot_signal_corr(app);
        end

        % Button pushed function: plotoptoresponseButton
        function plotoptoresponseButtonPushed(app, event)
            f_dv_opto_plot_response(app);
        end

        % Value changed function: ContoursDropDown
        function ContoursDropDownValueChanged(app, event)
            f_dv_set_contorus(app);
        end

        % Button pushed function: CloseallButton
        function CloseallButtonPushed(app, event)
            close all;
        end

        % Button pushed function: enslesscorrsampcellsButton
        function enslesscorrsampcellsButtonPushed(app, event)
            f_dv_ensless_corr_samp_cells(app);
        end

        % Button pushed function: PlottuningButton
        function PlottuningButtonPushed(app, event)
            f_dv_plot_tuning(app);
        end

        % Button pushed function: plotwftuningButton
        function plotwftuningButtonPushed(app, event)
            f_dv_plot_wf_tuning(app);
        end

        % Button pushed function: plottrialavePCAButton
        function plottrialavePCAButtonPushed(app, event)
            f_dv_plot_trial_ave_pca(app);
        end

        % Button pushed function: plotwfconvButton
        function plotwfconvButtonPushed(app, event)
            f_dv_plot_wf_conv(app);
        end

        % Button pushed function: lowdimprojtrialButton
        function lowdimprojtrialButtonPushed(app, event)
            f_dv_low_dim_proj_trial(app);
        end

        % Button pushed function: lowdimprojcellButton
        function lowdimprojcellButtonPushed(app, event)
            f_dv_low_dim_proj_cell(app);
        end

        % Button pushed function: isomap2Button
        function isomap2ButtonPushed(app, event)
            f_dv_isomap(app);
        end

        % Button pushed function: lowdimprojfulldataButton
        function lowdimprojfulldataButtonPushed(app, event)
            f_dv_low_dim_proj_full_data(app);
        end

        % Button pushed function: lowdimprojtrialctxButton
        function lowdimprojtrialctxButtonPushed(app, event)
            f_dv_low_dim_proj_trial_ctx(app);
        end

        % Button pushed function: lowdimprojcellctxButton
        function lowdimprojcellctxButtonPushed(app, event)
            f_dv_low_dim_proj_cell_ctx(app);
        end

        % Button pushed function: somecellplotsButton
        function somecellplotsButtonPushed(app, event)
            f_dv_some_cell_plots(app);
        end

        % Button pushed function: plotdistbyareaButton
        function plotdistbyareaButtonPushed(app, event)
            f_dv_plot_dist_by_area(app);
        end

        % Button pushed function: dsetplotindivtrialsButton
        function dsetplotindivtrialsButtonPushed(app, event)
            f_dv_dset_plot_indiv_trials(app);
        end

        % Button pushed function: dimesttrialshuffButton
        function dimesttrialshuffButtonPushed(app, event)
            f_dv_dim_est_trial_shuff(app);
        end

        % Button pushed function: continteractionplotsButton
        function continteractionplotsButtonPushed(app, event)
            f_dv_cont_interaction_plots(app);
        end

        % Button pushed function: plotpopmmnButton
        function plotpopmmnButtonPushed(app, event)
            f_dv_plot_pop_mmn(app);
        end

        % Button pushed function: plotcolorlegendsButton
        function plotcolorlegendsButtonPushed(app, event)
            f_dv_plot_color_legends(app);
        end

        % Button pushed function: plotsingletrialPCAButton
        function plotsingletrialPCAButtonPushed(app, event)
            f_dv_plot_single_trial_PCA(app);
        end

        % Button pushed function: distancesButton
        function distancesButtonPushed(app, event)
            f_dv_low_dim_distances(app);
        end

        % Button pushed function: lowdimprojtrial2Button
        function lowdimprojtrial2ButtonPushed(app, event)
            f_dv_low_dim_proj_trial2(app);
        end

        % Button pushed function: lowdimprojcell2Button
        function lowdimprojcell2ButtonPushed(app, event)
            f_dv_low_dim_proj_cell2(app)
        end

        % Button pushed function: MvRegressionButton
        function MvRegressionButtonPushed(app, event)
            f_dv_mv_regression(app);
        end

        % Button pushed function: popavefreqButton
        function popavefreqButtonPushed(app, event)
            f_dv_plot_pop_ave_freq(app);
        end

        % Button pushed function: plotdsetstatsButton
        function plotdsetstatsButtonPushed(app, event)
            f_dv_plot_dset_stats(app);
        end

        % Button pushed function: moretuningplotsButton
        function moretuningplotsButtonPushed(app, event)
            f_dv_more_tuining_plot(app);
        end

        % Button pushed function: decoderonevallButton
        function decoderonevallButtonPushed(app, event)
            f_dv_decoder_onevall(app);
        end

        % Button pushed function: decoderonevoneButton
        function decoderonevoneButtonPushed(app, event)
            f_dv_decoder_onevone(app);
        end

        % Button pushed function: similarityonevoneButton
        function similarityonevoneButtonPushed(app, event)
            f_dv_similarity_onevone(app);
        end

        % Button pushed function: tuningacrossdsetsButton
        function tuningacrossdsetsButtonPushed(app, event)
            f_dv_tuning_across_dsets(app);
        end

        % Button pushed function: MMNacrossdsetsButton
        function MMNacrossdsetsButtonPushed(app, event)
            f_dv_mmn_across_dsets(app);
        end

        % Button pushed function: plotbarButton
        function plotbarButtonPushed(app, event)
            f_dv_plot_bar_features(app);
        end

        % Button pushed function: DecodertuningButton
        function DecodertuningButtonPushed(app, event)
            f_dv_decoder_tuning(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'A')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Trace')
            xlabel(app.UIAxes2, 'Time (sec)')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxesColorPallet = uiaxes(app.UIFigure);
            xlabel(app.UIAxesColorPallet, 'Contour color')
            app.UIAxesColorPallet.YTick = [];
            app.UIAxesColorPallet.YTickLabel = '';
            app.MinLabel = uilabel(app.UIFigure);
            app.MinLabel.Text = 'Min';
            app.ContourMinEditField = uieditfield(app.UIFigure, 'numeric');
            app.ContourMinEditField.ValueChangedFcn = createCallbackFcn(app, @ContourMinEditFieldValueChanged, true);
            app.MaxLabel = uilabel(app.UIFigure);
            app.MaxLabel.Text = 'Max';
            app.ContourMaxEditField = uieditfield(app.UIFigure, 'numeric');
            app.ContourMaxEditField.ValueChangedFcn = createCallbackFcn(app, @ContourMaxEditFieldValueChanged, true);
            app.ContourlimitsLabel = uilabel(app.UIFigure);
            app.ContourlimitsLabel.Text = 'Contour limits';
            app.Runs31Button = uibutton(app.UIFigure, 'push');
            app.Runs31Button.ButtonPushedFcn = createCallbackFcn(app, @Runs31ButtonPushed, true);
            app.Runs31Button.Text = 'Run s31';
            app.DatasetDropDownLabel = uilabel(app.UIFigure);
            app.DatasetDropDownLabel.Text = 'Dataset';
            app.DatasetDropDown = uidropdown(app.UIFigure);
            app.DatasetDropDown.Items = {};
            app.DatasetDropDown.ValueChangedFcn = createCallbackFcn(app, @DatasetDropDownValueChanged, true);
            app.DatasetDropDown.Value = {};
            app.mplSpinnerLabel = uilabel(app.UIFigure);
            app.mplSpinnerLabel.Text = 'mpl';
            app.mplSpinner = uispinner(app.UIFigure);
            app.mplSpinner.Limits = [1 Inf];
            app.mplSpinner.RoundFractionalValues = 'on';
            app.mplSpinner.ValueChangedFcn = createCallbackFcn(app, @mplSpinnerValueChanged, true);
            app.mplSpinner.Value = 1;
            app.CellSpinnerLabel = uilabel(app.UIFigure);
            app.CellSpinnerLabel.Text = 'Cell';
            app.CellSpinner = uispinner(app.UIFigure);
            app.CellSpinner.Limits = [1 Inf];
            app.CellSpinner.RoundFractionalValues = 'on';
            app.CellSpinner.ValueChangedFcn = createCallbackFcn(app, @CellSpinnerValueChanged, true);
            app.CellSpinner.Value = 1;
            app.regdatapathEditFieldLabel = uilabel(app.UIFigure);
            app.regdatapathEditFieldLabel.Text = 'reg data path';
            app.regdatapathEditField = uieditfield(app.UIFigure, 'text');
            app.LoadregdataButton = uibutton(app.UIFigure, 'push');
            app.LoadregdataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadregdataButtonPushed, true);
            app.LoadregdataButton.Text = 'Load reg data';
            app.UpdatefigsCheckBox = uicheckbox(app.UIFigure);
            app.UpdatefigsCheckBox.Text = 'Update figs';
            app.UpdatefigsCheckBox.Value = true;
            app.TabGroup = uitabgroup(app.UIFigure);
            app.OpsTab = uitab(app.TabGroup);
            app.OpsTab.Title = 'Ops';
            app.SpikesPanel = uipanel(app.OpsTab);
            app.SpikesPanel.Title = 'Spikes';
            app.DeconvolutionmethodDropDownLabel = uilabel(app.SpikesPanel);
            app.DeconvolutionmethodDropDownLabel.Text = 'Deconvolution method';
            app.DeconvolutionmethodDropDown = uidropdown(app.SpikesPanel);
            app.DeconvolutionmethodDropDown.Items = {};
            app.DeconvolutionmethodDropDown.ValueChangedFcn = createCallbackFcn(app, @DeconvolutionmethodDropDownValueChanged, true);
            app.DeconvolutionmethodDropDown.Value = {};
            app.SmoothsigmamsEditFieldLabel = uilabel(app.SpikesPanel);
            app.SmoothsigmamsEditFieldLabel.Text = 'Smooth sigma (ms)';
            app.SmoothsigmamsEditField = uieditfield(app.SpikesPanel, 'numeric');
            app.SmoothsigmamsEditField.Value = 150;
            app.SmoothCheckBox = uicheckbox(app.SpikesPanel);
            app.SmoothCheckBox.ValueChangedFcn = createCallbackFcn(app, @SmoothCheckBoxValueChanged, true);
            app.SmoothCheckBox.Text = 'Smooth';
            app.SmoothCheckBox.Value = true;
            app.ScaleEditFieldLabel = uilabel(app.SpikesPanel);
            app.ScaleEditFieldLabel.Text = 'Scale';
            app.ScaleEditField_sp = uieditfield(app.SpikesPanel, 'numeric');
            app.ScaleEditField_sp.Value = 1;
            app.ShiftEditFieldLabel = uilabel(app.SpikesPanel);
            app.ShiftEditFieldLabel.Text = 'Shift';
            app.ShiftEditField_sp = uieditfield(app.SpikesPanel, 'numeric');
            app.VolttracePanel = uipanel(app.OpsTab);
            app.VolttracePanel.Title = 'Volt trace';
            app.VolttraceButton = uibutton(app.VolttracePanel, 'state');
            app.VolttraceButton.Text = 'Volt trace';
            app.VoltchanDropDownLabel = uilabel(app.VolttracePanel);
            app.VoltchanDropDownLabel.Text = 'Volt chan';
            app.VoltchanDropDown = uidropdown(app.VolttracePanel);
            app.VoltchanDropDown.Items = {};
            app.VoltchanDropDown.Value = {};
            app.ScaleEditField_3Label_2 = uilabel(app.VolttracePanel);
            app.ScaleEditField_3Label_2.Text = 'Scale';
            app.ScaleEditField_volt = uieditfield(app.VolttracePanel, 'numeric');
            app.ScaleEditField_volt.Value = 1;
            app.ShiftEditField_3Label_2 = uilabel(app.VolttracePanel);
            app.ShiftEditField_3Label_2.Text = 'Shift';
            app.ShiftEditField_volt = uieditfield(app.VolttracePanel, 'numeric');
            app.StimtimesPanel = uipanel(app.OpsTab);
            app.StimtimesPanel.Title = 'Stim times';
            app.ScaleEditField_3Label = uilabel(app.StimtimesPanel);
            app.ScaleEditField_3Label.Text = 'Scale';
            app.ScaleEditField_st = uieditfield(app.StimtimesPanel, 'numeric');
            app.ScaleEditField_st.Value = 1;
            app.ShiftEditField_3Label = uilabel(app.StimtimesPanel);
            app.ShiftEditField_3Label.Text = 'Shift';
            app.ShiftEditField_st = uieditfield(app.StimtimesPanel, 'numeric');
            app.ColormapDropDownLabel = uilabel(app.OpsTab);
            app.ColormapDropDownLabel.Text = 'Colormap';
            app.ColormapDropDown = uidropdown(app.OpsTab);
            app.ColormapDropDown.Items = {};
            app.ColormapDropDown.Value = {};
            app.MattridataplotDropDownLabel = uilabel(app.OpsTab);
            app.MattridataplotDropDownLabel.Text = 'Mat tri data plot';
            app.MattridataplotDropDown = uidropdown(app.OpsTab);
            app.MattridataplotDropDown.Items = {};
            app.MattridataplotDropDown.Value = {};
            app.DistmethodDropDownLabel = uilabel(app.OpsTab);
            app.DistmethodDropDownLabel.Text = 'Dist method';
            app.DistmethodDropDown = uidropdown(app.OpsTab);
            app.DistmethodDropDown.Items = {};
            app.DistmethodDropDown.Value = {};
            app.DimredmethodDropDownLabel = uilabel(app.OpsTab);
            app.DimredmethodDropDownLabel.Text = 'Dim red method';
            app.DimredmethodDropDown = uidropdown(app.OpsTab);
            app.DimredmethodDropDown.Items = {};
            app.DimredmethodDropDown.Value = {};
            app.DistreferenceDropDownLabel = uilabel(app.OpsTab);
            app.DistreferenceDropDownLabel.Text = 'Dist reference';
            app.DistreferenceDropDown = uidropdown(app.OpsTab);
            app.DistreferenceDropDown.Items = {};
            app.DistreferenceDropDown.Value = {};
            app.dosimilarityCheckBox = uicheckbox(app.OpsTab);
            app.dosimilarityCheckBox.Text = 'do similarity';
            app.normalizeeucCheckBox = uicheckbox(app.OpsTab);
            app.normalizeeucCheckBox.Text = 'normalize euc';
            app.InvertcmapCheckBox = uicheckbox(app.OpsTab);
            app.InvertcmapCheckBox.Text = 'Invert cmap';
            app.nanhandlemetDropDownLabel = uilabel(app.OpsTab);
            app.nanhandlemetDropDownLabel.Text = 'nan handle met';
            app.nanhandlemetDropDown = uidropdown(app.OpsTab);
            app.nanhandlemetDropDown.Items = {};
            app.nanhandlemetDropDown.Value = {};
            app.FigrenderpaintersCheckBox = uicheckbox(app.OpsTab);
            app.FigrenderpaintersCheckBox.Text = 'Fig render painters';
            app.gridon3dCheckBox = uicheckbox(app.OpsTab);
            app.gridon3dCheckBox.Text = 'grid on 3d';
            app.gridon3dCheckBox.Value = true;
            app.shadowon3dCheckBox = uicheckbox(app.OpsTab);
            app.shadowon3dCheckBox.Text = 'shadow on 3d';
            app.shadowon3dCheckBox.Value = true;
            app.BarstatstypeDropDownLabel = uilabel(app.OpsTab);
            app.BarstatstypeDropDownLabel.Text = 'Bar stats type';
            app.BarstatstypeDropDown = uidropdown(app.OpsTab);
            app.BarstatstypeDropDown.Items = {};
            app.BarstatstypeDropDown.Value = {};
            app.BarplottypeDropDownLabel = uilabel(app.OpsTab);
            app.BarplottypeDropDownLabel.Text = 'Bar plot type';
            app.BarplottypeDropDown = uidropdown(app.OpsTab);
            app.BarplottypeDropDown.Items = {};
            app.BarplottypeDropDown.Value = {};
            app.singlecellTab = uitab(app.TabGroup);
            app.singlecellTab.Title = 'single cell';
            app.PlotfreqrespButton = uibutton(app.singlecellTab, 'push');
            app.PlotfreqrespButton.ButtonPushedFcn = createCallbackFcn(app, @PlotfreqrespButtonPushed, true);
            app.PlotfreqrespButton.Text = 'Plot freq resp';
            app.PlotctxrespButton = uibutton(app.singlecellTab, 'push');
            app.PlotctxrespButton.ButtonPushedFcn = createCallbackFcn(app, @PlotctxrespButtonPushed, true);
            app.PlotctxrespButton.Text = 'Plot ctx resp';
            app.FullredCheckBox = uicheckbox(app.singlecellTab);
            app.FullredCheckBox.Text = 'Full red';
            app.NewplotsCheckBox = uicheckbox(app.singlecellTab);
            app.NewplotsCheckBox.Text = 'New plots';
            app.PlotselecttrialButton = uibutton(app.singlecellTab, 'push');
            app.PlotselecttrialButton.ButtonPushedFcn = createCallbackFcn(app, @PlotselecttrialButtonPushed, true);
            app.PlotselecttrialButton.Text = 'Plot select trial';
            app.SNREditFieldLabel = uilabel(app.singlecellTab);
            app.SNREditFieldLabel.Text = 'SNR';
            app.SNREditField = uieditfield(app.singlecellTab, 'numeric');
            app.SNREditField.Editable = 'off';
            app.MMNfreqEditFieldLabel = uilabel(app.singlecellTab);
            app.MMNfreqEditFieldLabel.Text = 'MMN freq';
            app.MMNfreqEditField = uieditfield(app.singlecellTab, 'text');
            app.MMNfreqEditField.Editable = 'off';
            app.FramerateEditFieldLabel = uilabel(app.singlecellTab);
            app.FramerateEditFieldLabel.Text = 'Frame rate';
            app.FramerateEditField = uieditfield(app.singlecellTab, 'numeric');
            app.FramerateEditField.Editable = 'off';
            app.PlotregButton = uibutton(app.singlecellTab, 'push');
            app.PlotregButton.ButtonPushedFcn = createCallbackFcn(app, @PlotregButtonPushed, true);
            app.PlotregButton.Text = 'Plot reg';
            app.SizemagadjustCheckBox = uicheckbox(app.singlecellTab);
            app.SizemagadjustCheckBox.Text = 'Size mag adjust';
            app.SizefactorEditFieldLabel = uilabel(app.singlecellTab);
            app.SizefactorEditFieldLabel.Text = 'Size factor';
            app.SizefactorEditField = uieditfield(app.singlecellTab, 'numeric');
            app.SizefactorEditField.Value = 0.5;
            app.numfreqSpinnerLabel = uilabel(app.singlecellTab);
            app.numfreqSpinnerLabel.Text = 'num freq';
            app.numfreqSpinner = uispinner(app.singlecellTab);
            app.numfreqSpinner.Limits = [1 10];
            app.numfreqSpinner.RoundFractionalValues = 'on';
            app.numfreqSpinner.ValueChangedFcn = createCallbackFcn(app, @numfreqSpinnerValueChanged, true);
            app.numfreqSpinner.Value = 1;
            app.ConverttoZCheckBox = uicheckbox(app.singlecellTab);
            app.ConverttoZCheckBox.ValueChangedFcn = createCallbackFcn(app, @ConverttoZCheckBoxValueChanged, true);
            app.ConverttoZCheckBox.Text = 'Convert to Z';
            app.ConverttoZCheckBox.Value = true;
            app.IndividualtrialsCheckBox = uicheckbox(app.singlecellTab);
            app.IndividualtrialsCheckBox.Text = 'Individual trials';
            app.IndividualtrialsCheckBox.Value = true;
            app.ColoredbytimeCheckBox = uicheckbox(app.singlecellTab);
            app.ColoredbytimeCheckBox.Text = 'Colored by time';
            app.UseregdatalabelsCheckBox = uicheckbox(app.singlecellTab);
            app.UseregdatalabelsCheckBox.Text = 'Use reg data labels';
            app.UseregdatalabelsCheckBox.Value = true;
            app.poolregionsCheckBox = uicheckbox(app.singlecellTab);
            app.poolregionsCheckBox.Text = 'pool regions';
            app.poolregionsCheckBox.Value = true;
            app.PlotbaserespwinsecLabel = uilabel(app.singlecellTab);
            app.PlotbaserespwinsecLabel.Text = 'Plot base resp win (sec)';
            app.plot_BaserespwinEditField = uieditfield(app.singlecellTab, 'text');
            app.AnalysisbaserespwinsecLabel = uilabel(app.singlecellTab);
            app.AnalysisbaserespwinsecLabel.Text = 'Analysis base resp win (sec)';
            app.analysis_BaserespwinEditField = uieditfield(app.singlecellTab, 'text');
            app.ParadigmEditFieldLabel = uilabel(app.singlecellTab);
            app.ParadigmEditFieldLabel.Text = 'Paradigm';
            app.ParadigmEditField = uieditfield(app.singlecellTab, 'text');
            app.ParadigmEditField.Editable = 'off';
            app.plotfractionsCheckBox = uicheckbox(app.singlecellTab);
            app.plotfractionsCheckBox.Text = 'plot fractions';
            app.plotensamblesCheckBox = uicheckbox(app.singlecellTab);
            app.plotensamblesCheckBox.Text = 'plot ensambles';
            app.PhaseplotCheckBox = uicheckbox(app.singlecellTab);
            app.PhaseplotCheckBox.Text = 'Phase plot';
            app.PlottuningButton = uibutton(app.singlecellTab, 'push');
            app.PlottuningButton.ButtonPushedFcn = createCallbackFcn(app, @PlottuningButtonPushed, true);
            app.PlottuningButton.Text = 'Plot tuning';
            app.plotwftuningButton = uibutton(app.singlecellTab, 'push');
            app.plotwftuningButton.ButtonPushedFcn = createCallbackFcn(app, @plotwftuningButtonPushed, true);
            app.plotwftuningButton.Text = 'plot wf tuning';
            app.plotwfconvButton = uibutton(app.singlecellTab, 'push');
            app.plotwfconvButton.ButtonPushedFcn = createCallbackFcn(app, @plotwfconvButtonPushed, true);
            app.plotwfconvButton.Text = 'plot wf conv';
            app.somecellplotsButton = uibutton(app.singlecellTab, 'push');
            app.somecellplotsButton.ButtonPushedFcn = createCallbackFcn(app, @somecellplotsButtonPushed, true);
            app.somecellplotsButton.Text = 'some cell plots';
            app.plotdsetstatsButton = uibutton(app.singlecellTab, 'push');
            app.plotdsetstatsButton.ButtonPushedFcn = createCallbackFcn(app, @plotdsetstatsButtonPushed, true);
            app.plotdsetstatsButton.Text = 'plot dset stats';
            app.WhitebkgCheckBox = uicheckbox(app.singlecellTab);
            app.WhitebkgCheckBox.Text = 'White bkg';
            app.WhitebkgCheckBox.Value = true;
            app.AreabordersCheckBox = uicheckbox(app.singlecellTab);
            app.AreabordersCheckBox.Text = 'Area borders';
            app.AreabordersCheckBox.Value = true;
            app.PlotnontunedCheckBox = uicheckbox(app.singlecellTab);
            app.PlotnontunedCheckBox.Text = 'Plot nontuned';
            app.normpeakwithinCheckBox = uicheckbox(app.singlecellTab);
            app.normpeakwithinCheckBox.Text = 'norm peak within';
            app.normpeakwithinCheckBox.Value = true;
            app.singlecell2Tab = uitab(app.TabGroup);
            app.singlecell2Tab.Title = 'single cell2';
            app.PlottuningdistButton = uibutton(app.singlecell2Tab, 'push');
            app.PlottuningdistButton.ButtonPushedFcn = createCallbackFcn(app, @PlottuningdistButtonPushed, true);
            app.PlottuningdistButton.Text = 'Plot tuning dist';
            app.plotdistButton = uibutton(app.singlecell2Tab, 'push');
            app.plotdistButton.ButtonPushedFcn = createCallbackFcn(app, @plotdistButtonPushed, true);
            app.plotdistButton.Text = 'plot dist';
            app.plotMMNButton = uibutton(app.singlecell2Tab, 'push');
            app.plotMMNButton.ButtonPushedFcn = createCallbackFcn(app, @plotMMNButtonPushed, true);
            app.plotMMNButton.Text = 'plot MMN';
            app.MarginalizedistCheckBox = uicheckbox(app.singlecell2Tab);
            app.MarginalizedistCheckBox.Text = 'Marginalize dist';
            app.plotfeatureDropDownLabel = uilabel(app.singlecell2Tab);
            app.plotfeatureDropDownLabel.Text = 'plot feature';
            app.plotfeatureDropDown = uidropdown(app.singlecell2Tab);
            app.plotfeatureDropDown.Items = {};
            app.plotfeatureDropDown.Value = {};
            app.plotxyButton = uibutton(app.singlecell2Tab, 'push');
            app.plotxyButton.ButtonPushedFcn = createCallbackFcn(app, @plotxyButtonPushed, true);
            app.plotxyButton.Tooltip = {'params: resp thresh, marginalize dist, Limit resp trials'};
            app.plotxyButton.Text = 'plot xy';
            app.plotCDRButton = uibutton(app.singlecell2Tab, 'push');
            app.plotCDRButton.ButtonPushedFcn = createCallbackFcn(app, @plotCDRButtonPushed, true);
            app.plotCDRButton.Text = 'plot CDR';
            app.plottrialavePCAButton = uibutton(app.singlecell2Tab, 'push');
            app.plottrialavePCAButton.ButtonPushedFcn = createCallbackFcn(app, @plottrialavePCAButtonPushed, true);
            app.plottrialavePCAButton.Text = 'plot trial ave PCA';
            app.plotdistbyareaButton = uibutton(app.singlecell2Tab, 'push');
            app.plotdistbyareaButton.ButtonPushedFcn = createCallbackFcn(app, @plotdistbyareaButtonPushed, true);
            app.plotdistbyareaButton.Text = 'plot dist by area';
            app.continteractionplotsButton = uibutton(app.singlecell2Tab, 'push');
            app.continteractionplotsButton.ButtonPushedFcn = createCallbackFcn(app, @continteractionplotsButtonPushed, true);
            app.continteractionplotsButton.Text = 'cont interaction plots';
            app.plotpopmmnButton = uibutton(app.singlecell2Tab, 'push');
            app.plotpopmmnButton.ButtonPushedFcn = createCallbackFcn(app, @plotpopmmnButtonPushed, true);
            app.plotpopmmnButton.Text = 'plot pop mmn';
            app.plotsuperdeetsCheckBox = uicheckbox(app.singlecell2Tab);
            app.plotsuperdeetsCheckBox.Text = 'plot super deets';
            app.plotstatsCheckBox = uicheckbox(app.singlecell2Tab);
            app.plotstatsCheckBox.Text = 'plot stats';
            app.PCAdistancesCheckBox = uicheckbox(app.singlecell2Tab);
            app.PCAdistancesCheckBox.Text = 'PCA distances';
            app.plotsingletrialPCAButton = uibutton(app.singlecell2Tab, 'push');
            app.plotsingletrialPCAButton.ButtonPushedFcn = createCallbackFcn(app, @plotsingletrialPCAButtonPushed, true);
            app.plotsingletrialPCAButton.Text = 'plot single trial PCA';
            app.xvarDropDownLabel = uilabel(app.singlecell2Tab);
            app.xvarDropDownLabel.Text = 'x var';
            app.xvarDropDown = uidropdown(app.singlecell2Tab);
            app.xvarDropDown.Items = {};
            app.xvarDropDown.Value = {};
            app.yvarDropDownLabel = uilabel(app.singlecell2Tab);
            app.yvarDropDownLabel.Text = 'y var';
            app.yvarDropDown = uidropdown(app.singlecell2Tab);
            app.yvarDropDown.Items = {};
            app.yvarDropDown.Value = {};
            app.statsbetweenDropDownLabel = uilabel(app.singlecell2Tab);
            app.statsbetweenDropDownLabel.Text = 'stats between';
            app.statsbetweenDropDown = uidropdown(app.singlecell2Tab);
            app.statsbetweenDropDown.Items = {};
            app.statsbetweenDropDown.Value = {};
            app.plotaxesDropDownLabel = uilabel(app.singlecell2Tab);
            app.plotaxesDropDownLabel.Text = 'plot axes';
            app.plotaxesDropDown = uidropdown(app.singlecell2Tab);
            app.plotaxesDropDown.Items = {'2', '3'};
            app.plotaxesDropDown.Value = '2';
            app.plottypeDropDownLabel = uilabel(app.singlecell2Tab);
            app.plottypeDropDownLabel.Text = 'plot type';
            app.plottypeDropDown = uidropdown(app.singlecell2Tab);
            app.plottypeDropDown.Items = {};
            app.plottypeDropDown.Value = {};
            app.popavefreqButton = uibutton(app.singlecell2Tab, 'push');
            app.popavefreqButton.ButtonPushedFcn = createCallbackFcn(app, @popavefreqButtonPushed, true);
            app.popavefreqButton.Text = 'pop ave freq';
            app.plotPCAdimSpinnerLabel = uilabel(app.singlecell2Tab);
            app.plotPCAdimSpinnerLabel.Text = 'plot PCA dim';
            app.plotPCAdimSpinner = uispinner(app.singlecell2Tab);
            app.plotPCAdimSpinner.Limits = [1 Inf];
            app.plotPCAdimSpinner.RoundFractionalValues = 'on';
            app.plotPCAdimSpinner.Value = 6;
            app.stimcolorSpinnerLabel = uilabel(app.singlecell2Tab);
            app.stimcolorSpinnerLabel.Text = 'stim color';
            app.stimcolorSpinner = uispinner(app.singlecell2Tab);
            app.stimcolorSpinner.Limits = [1 30];
            app.stimcolorSpinner.RoundFractionalValues = 'on';
            app.stimcolorSpinner.Value = 4;
            app.StimtranspEditFieldLabel = uilabel(app.singlecell2Tab);
            app.StimtranspEditFieldLabel.Text = 'Stim transp';
            app.StimtranspEditField = uieditfield(app.singlecell2Tab, 'numeric');
            app.StimtranspEditField.Limits = [0 1];
            app.StimtranspEditField.Value = 0.2;
            app.PlotstimCheckBox = uicheckbox(app.singlecell2Tab);
            app.PlotstimCheckBox.Text = 'Plot stim';
            app.PlotstimCheckBox.Value = true;
            app.moretuningplotsButton = uibutton(app.singlecell2Tab, 'push');
            app.moretuningplotsButton.ButtonPushedFcn = createCallbackFcn(app, @moretuningplotsButtonPushed, true);
            app.moretuningplotsButton.Text = 'more tuning plots';
            app.kdesmfactorEditFieldLabel = uilabel(app.singlecell2Tab);
            app.kdesmfactorEditFieldLabel.Text = 'kde sm factor';
            app.kdesmfactorEditField = uieditfield(app.singlecell2Tab, 'numeric');
            app.kdesmfactorEditField.Value = 1;
            app.maxYlimEditFieldLabel = uilabel(app.singlecell2Tab);
            app.maxYlimEditFieldLabel.Text = 'max Y lim';
            app.maxYlimEditField = uieditfield(app.singlecell2Tab, 'numeric');
            app.minYlimEditFieldLabel = uilabel(app.singlecell2Tab);
            app.minYlimEditFieldLabel.Text = 'min Y lim';
            app.minYlimEditField = uieditfield(app.singlecell2Tab, 'numeric');
            app.winanalyzeDropDownLabel = uilabel(app.singlecell2Tab);
            app.winanalyzeDropDownLabel.Text = 'win analyze';
            app.winanalyzeDropDown = uidropdown(app.singlecell2Tab);
            app.winanalyzeDropDown.Items = {};
            app.winanalyzeDropDown.Value = {};
            app.plotbarButton = uibutton(app.singlecell2Tab, 'push');
            app.plotbarButton.ButtonPushedFcn = createCallbackFcn(app, @plotbarButtonPushed, true);
            app.plotbarButton.Text = 'plot bar';
            app.DecodertuningButton = uibutton(app.singlecell2Tab, 'push');
            app.DecodertuningButton.ButtonPushedFcn = createCallbackFcn(app, @DecodertuningButtonPushed, true);
            app.DecodertuningButton.Text = 'Decoder tuning';
            app.statsTab = uitab(app.TabGroup);
            app.statsTab.Title = 'stats';
            app.NormalizemaxspikesCheckBox = uicheckbox(app.statsTab);
            app.NormalizemaxspikesCheckBox.ValueChangedFcn = createCallbackFcn(app, @NormalizemaxspikesCheckBoxValueChanged, true);
            app.NormalizemaxspikesCheckBox.Text = 'Normalize max spikes';
            app.NormalizemaxspikesCheckBox.Value = true;
            app.SubtractmeanspikesCheckBox = uicheckbox(app.statsTab);
            app.SubtractmeanspikesCheckBox.ValueChangedFcn = createCallbackFcn(app, @SubtractmeanspikesCheckBoxValueChanged, true);
            app.SubtractmeanspikesCheckBox.Text = 'Subtract mean spikes';
            app.SubtractmeanspikesCheckBox.Value = true;
            app.RectifyspikesCheckBox = uicheckbox(app.statsTab);
            app.RectifyspikesCheckBox.ValueChangedFcn = createCallbackFcn(app, @RectifyspikesCheckBoxValueChanged, true);
            app.RectifyspikesCheckBox.Text = 'Rectify spikes';
            app.RectifyspikesCheckBox.Value = true;
            app.computecdataButton = uibutton(app.statsTab, 'push');
            app.computecdataButton.ButtonPushedFcn = createCallbackFcn(app, @computecdataButtonPushed, true);
            app.computecdataButton.Text = 'compute cdata';
            app.NewstatsparamsPanel = uipanel(app.statsTab);
            app.NewstatsparamsPanel.Title = 'New stats params';
            app.StatmethodDropDownLabel = uilabel(app.NewstatsparamsPanel);
            app.StatmethodDropDownLabel.Text = 'Stat method';
            app.StatsourceDropDownLabel = uilabel(app.NewstatsparamsPanel);
            app.StatsourceDropDownLabel.Text = 'Stat source';
            app.ZthreshEditFieldLabel_2 = uilabel(app.NewstatsparamsPanel);
            app.ZthreshEditFieldLabel_2.Text = 'Z thresh';
            app.PvalEditFieldLabel_2 = uilabel(app.NewstatsparamsPanel);
            app.PvalEditFieldLabel_2.Text = 'P-val';
            app.PeakbintimesecEditFieldLabel_2 = uilabel(app.NewstatsparamsPanel);
            app.PeakbintimesecEditFieldLabel_2.Text = 'Peak bin time (sec)';
            app.NumshuffsampEditFieldLabel = uilabel(app.NewstatsparamsPanel);
            app.NumshuffsampEditFieldLabel.Text = 'Num shuff samp';
            app.LocothreshEditFieldLabel_2 = uilabel(app.NewstatsparamsPanel);
            app.LocothreshEditFieldLabel_2.Text = 'Loco thresh';
            app.BaserespwindowEditFieldLabel = uilabel(app.NewstatsparamsPanel);
            app.BaserespwindowEditFieldLabel.Text = 'Base resp window';
            app.recomputestatsButton = uibutton(app.NewstatsparamsPanel, 'push');
            app.recomputestatsButton.ButtonPushedFcn = createCallbackFcn(app, @recomputestatsButtonPushed, true);
            app.recomputestatsButton.Text = 'recompute stats';
            app.stats_LocothreshEditField = uieditfield(app.NewstatsparamsPanel, 'numeric');
            app.stats_BaserespwinEditField = uieditfield(app.NewstatsparamsPanel, 'text');
            app.stats_NumshuffsampEditField = uieditfield(app.NewstatsparamsPanel, 'numeric');
            app.stats_PeakbintimesecEditField = uieditfield(app.NewstatsparamsPanel, 'numeric');
            app.stats_PvalEditField = uieditfield(app.NewstatsparamsPanel, 'numeric');
            app.stats_PvalEditField.ValueChangedFcn = createCallbackFcn(app, @stats_PvalEditFieldValueChanged, true);
            app.stats_ZthreshEditField = uieditfield(app.NewstatsparamsPanel, 'numeric');
            app.stats_ZthreshEditField.ValueChangedFcn = createCallbackFcn(app, @stats_ZthreshEditFieldValueChanged, true);
            app.stats_ZthreshEditField.Value = 3;
            app.stats_StatsourceDropDown = uidropdown(app.NewstatsparamsPanel);
            app.stats_StatsourceDropDown.Items = {};
            app.stats_StatsourceDropDown.ValueChangedFcn = createCallbackFcn(app, @stats_StatsourceDropDownValueChanged, true);
            app.stats_StatsourceDropDown.Value = {};
            app.stats_StatmethodDropDown = uidropdown(app.NewstatsparamsPanel);
            app.stats_StatmethodDropDown.Items = {};
            app.stats_StatmethodDropDown.ValueChangedFcn = createCallbackFcn(app, @stats_StatmethodDropDownValueChanged, true);
            app.stats_StatmethodDropDown.Value = {};
            app.LimsigrespwindowLabel = uilabel(app.NewstatsparamsPanel);
            app.LimsigrespwindowLabel.Text = 'Lim sig resp window';
            app.stats_LimSigRespwinEditField = uieditfield(app.NewstatsparamsPanel, 'text');
            app.OnsetrespwindowLabel = uilabel(app.NewstatsparamsPanel);
            app.OnsetrespwindowLabel.Text = 'Onset resp window';
            app.stats_OnsetRespwinEditField = uieditfield(app.NewstatsparamsPanel, 'text');
            app.OffsetrespwindowLabel = uilabel(app.NewstatsparamsPanel);
            app.OffsetrespwindowLabel.Text = 'Offset resp window';
            app.stats_OffsetRespwinEditField = uieditfield(app.NewstatsparamsPanel, 'text');
            app.MiddlerespwindowLabel = uilabel(app.NewstatsparamsPanel);
            app.MiddlerespwindowLabel.Text = 'Middle resp window';
            app.stats_MiddleRespwinEditField = uieditfield(app.NewstatsparamsPanel, 'text');
            app.statswithinButton = uibutton(app.statsTab, 'push');
            app.statswithinButton.ButtonPushedFcn = createCallbackFcn(app, @statswithinButtonPushed, true);
            app.statswithinButton.Text = 'stats within';
            app.MvRegressionButton = uibutton(app.statsTab, 'push');
            app.MvRegressionButton.ButtonPushedFcn = createCallbackFcn(app, @MvRegressionButtonPushed, true);
            app.MvRegressionButton.Text = 'Mv Regression';
            app.ensestimateTab = uitab(app.TabGroup);
            app.ensestimateTab.Title = 'ens estimate';
            app.dimestpcaparamsPanel = uipanel(app.ensestimateTab);
            app.dimestpcaparamsPanel.Title = 'dim est pca params';
            app.normalizeDropDownLabel = uilabel(app.dimestpcaparamsPanel);
            app.normalizeDropDownLabel.Text = 'normalize';
            app.shufflemethodDropDownLabel = uilabel(app.dimestpcaparamsPanel);
            app.shufflemethodDropDownLabel.Text = 'shuffle method';
            app.totaldimthreshEditField_2Label = uilabel(app.dimestpcaparamsPanel);
            app.totaldimthreshEditField_2Label.Text = 'total dim thresh';
            app.dimestnumrepsEditFieldLabel_2 = uilabel(app.dimestpcaparamsPanel);
            app.dimestnumrepsEditFieldLabel_2.Text = 'dim est num reps';
            app.DimpcaEditFieldLabel = uilabel(app.dimestpcaparamsPanel);
            app.DimpcaEditFieldLabel.Text = 'Dim pca';
            app.dimestpca_PlotstuffCheckBox = uicheckbox(app.dimestpcaparamsPanel);
            app.dimestpca_PlotstuffCheckBox.Text = 'Plot stuff';
            app.dimestpca_dimestnumrepsEditField = uieditfield(app.dimestpcaparamsPanel, 'numeric');
            app.dimestpca_totaldimthreshEditField = uieditfield(app.dimestpcaparamsPanel, 'numeric');
            app.dimestpca_shufflemethodDropDown = uidropdown(app.dimestpcaparamsPanel);
            app.dimestpca_shufflemethodDropDown.Items = {};
            app.dimestpca_shufflemethodDropDown.Value = {};
            app.dimestpca_normalizeDropDown = uidropdown(app.dimestpcaparamsPanel);
            app.dimestpca_normalizeDropDown.Items = {};
            app.dimestpca_normalizeDropDown.Value = {};
            app.EstimatedimpcaButton = uibutton(app.dimestpcaparamsPanel, 'push');
            app.EstimatedimpcaButton.ButtonPushedFcn = createCallbackFcn(app, @EstimatedimpcaButtonPushed, true);
            app.EstimatedimpcaButton.Text = 'Estimate dim pca';
            app.DimpcaEditField = uieditfield(app.dimestpcaparamsPanel, 'numeric');
            app.DimpcaEditField.Editable = 'off';
            app.dimestCVparamsPanel = uipanel(app.ensestimateTab);
            app.dimestCVparamsPanel.Title = 'dim est CV params';
            app.ensmethodDropDownLabel = uilabel(app.dimestCVparamsPanel);
            app.ensmethodDropDownLabel.Text = 'ens method';
            app.normalizeDropDownLabel_3 = uilabel(app.dimestCVparamsPanel);
            app.normalizeDropDownLabel_3.Text = 'normalize';
            app.smoothSDwindowEditFieldLabel = uilabel(app.dimestCVparamsPanel);
            app.smoothSDwindowEditFieldLabel.Text = 'smooth SD window';
            app.repsEditFieldLabel = uilabel(app.dimestCVparamsPanel);
            app.repsEditFieldLabel.Text = 'reps';
            app.numcompwindowEditFieldLabel = uilabel(app.dimestCVparamsPanel);
            app.numcompwindowEditFieldLabel.Text = 'num comp window';
            app.DimCVEditFieldLabel = uilabel(app.dimestCVparamsPanel);
            app.DimCVEditFieldLabel.Text = 'Dim CV';
            app.centerEditField_3Label = uilabel(app.dimestCVparamsPanel);
            app.centerEditField_3Label.Text = 'center';
            app.countEditFieldLabel = uilabel(app.dimestCVparamsPanel);
            app.countEditFieldLabel.Text = 'count';
            app.rangeEditField_3Label = uilabel(app.dimestCVparamsPanel);
            app.rangeEditField_3Label.Text = 'range';
            app.centerEditField_4Label = uilabel(app.dimestCVparamsPanel);
            app.centerEditField_4Label.Text = 'center';
            app.countEditField_2Label = uilabel(app.dimestCVparamsPanel);
            app.countEditField_2Label.Text = 'count';
            app.rangeEditField_4Label = uilabel(app.dimestCVparamsPanel);
            app.rangeEditField_4Label.Text = 'range';
            app.dimestcv_includeshuffversionCheckBox = uicheckbox(app.dimestCVparamsPanel);
            app.dimestcv_includeshuffversionCheckBox.Text = 'include shuff version';
            app.dimestcv_repsEditField = uieditfield(app.dimestCVparamsPanel, 'numeric');
            app.dimestcv_numcompwindowEditField = uieditfield(app.dimestCVparamsPanel, 'text');
            app.dimestcv_numcompwindowEditField.Editable = 'off';
            app.dimestcv_centeratdimpcaCheckBox = uicheckbox(app.dimestCVparamsPanel);
            app.dimestcv_centeratdimpcaCheckBox.ValueChangedFcn = createCallbackFcn(app, @dimestcv_centeratdimpcaCheckBoxValueChanged, true);
            app.dimestcv_centeratdimpcaCheckBox.Text = 'center at dim pca';
            app.dimestcv_nc_countEditField = uieditfield(app.dimestCVparamsPanel, 'numeric');
            app.dimestcv_nc_countEditField.ValueChangedFcn = createCallbackFcn(app, @dimestcv_nc_countEditFieldValueChanged, true);
            app.dimestcv_nc_rangeEditField = uieditfield(app.dimestCVparamsPanel, 'numeric');
            app.dimestcv_nc_rangeEditField.ValueChangedFcn = createCallbackFcn(app, @dimestcv_nc_rangeEditFieldValueChanged, true);
            app.dimestcv_nc_centerEditField = uieditfield(app.dimestCVparamsPanel, 'numeric');
            app.dimestcv_nc_centerEditField.ValueChangedFcn = createCallbackFcn(app, @dimestcv_nc_centerEditFieldValueChanged, true);
            app.dimestcv_smoothSDwindowEditField = uieditfield(app.dimestCVparamsPanel, 'text');
            app.dimestcv_smoothSDwindowEditField.Editable = 'off';
            app.dimestcv_sm_countEditField = uieditfield(app.dimestCVparamsPanel, 'numeric');
            app.dimestcv_sm_countEditField.ValueChangedFcn = createCallbackFcn(app, @dimestcv_sm_countEditFieldValueChanged, true);
            app.dimestcv_sm_rangeEditField = uieditfield(app.dimestCVparamsPanel, 'numeric');
            app.dimestcv_sm_rangeEditField.ValueChangedFcn = createCallbackFcn(app, @dimestcv_sm_rangeEditFieldValueChanged, true);
            app.dimestcv_sm_centerEditField = uieditfield(app.dimestCVparamsPanel, 'numeric');
            app.dimestcv_sm_centerEditField.ValueChangedFcn = createCallbackFcn(app, @dimestcv_sm_centerEditFieldValueChanged, true);
            app.dimestcv_shuffledatachunksCheckBox = uicheckbox(app.dimestCVparamsPanel);
            app.dimestcv_shuffledatachunksCheckBox.Text = 'shuffle data chunks';
            app.dimestcv_ensmethodDropDown = uidropdown(app.dimestCVparamsPanel);
            app.dimestcv_ensmethodDropDown.Items = {};
            app.dimestcv_ensmethodDropDown.Value = {};
            app.dimestcv_normalizeDropDown = uidropdown(app.dimestCVparamsPanel);
            app.dimestcv_normalizeDropDown.Items = {};
            app.dimestcv_normalizeDropDown.Value = {};
            app.EstimatedimCVButton = uibutton(app.dimestCVparamsPanel, 'push');
            app.EstimatedimCVButton.ButtonPushedFcn = createCallbackFcn(app, @EstimatedimCVButtonPushed, true);
            app.EstimatedimCVButton.Text = 'Estimate dim CV';
            app.DimCVEditField = uieditfield(app.dimestCVparamsPanel, 'numeric');
            app.DimCVEditField.Editable = 'off';
            app.PlotdimCVButton = uibutton(app.dimestCVparamsPanel, 'push');
            app.PlotdimCVButton.ButtonPushedFcn = createCallbackFcn(app, @PlotdimCVButtonPushed, true);
            app.PlotdimCVButton.Text = 'Plot dim CV';
            app.ensfindTab = uitab(app.TabGroup);
            app.ensfindTab.Title = 'ens find';
            app.newensembleparamsPanel = uipanel(app.ensfindTab);
            app.newensembleparamsPanel.Title = 'new ensemble params';
            app.ensmethodLabel = uilabel(app.newensembleparamsPanel);
            app.ensmethodLabel.Text = 'ens method';
            app.normalizeDropDownLabel_2 = uilabel(app.newensembleparamsPanel);
            app.normalizeDropDownLabel_2.Text = 'normalize';
            app.ensextractionDropDownLabel = uilabel(app.newensembleparamsPanel);
            app.ensextractionDropDownLabel.Text = 'ens extraction';
            app.extractionthreshDropDownLabel = uilabel(app.newensembleparamsPanel);
            app.extractionthreshDropDownLabel.Text = 'extraction thresh';
            app.signalzthreshEditFieldLabel = uilabel(app.newensembleparamsPanel);
            app.signalzthreshEditFieldLabel.Text = 'signal z thresh';
            app.shuffthreshprcEditFieldLabel = uilabel(app.newensembleparamsPanel);
            app.shuffthreshprcEditFieldLabel.Text = 'shuff thresh prc';
            app.hclustmethodDropDownLabel = uilabel(app.newensembleparamsPanel);
            app.hclustmethodDropDownLabel.Text = 'hclust method';
            app.hclustmetricDropDownLabel = uilabel(app.newensembleparamsPanel);
            app.hclustmetricDropDownLabel.Text = 'hclust metric';
            app.ens_plotstuffCheckBox = uicheckbox(app.newensembleparamsPanel);
            app.ens_plotstuffCheckBox.Text = 'plot stuff';
            app.ens_hclustmetricDropDown = uidropdown(app.newensembleparamsPanel);
            app.ens_hclustmetricDropDown.Items = {};
            app.ens_hclustmetricDropDown.Value = {};
            app.ens_hclustmethodDropDown = uidropdown(app.newensembleparamsPanel);
            app.ens_hclustmethodDropDown.Items = {};
            app.ens_hclustmethodDropDown.Value = {};
            app.ens_shuffthreshprcEditField = uieditfield(app.newensembleparamsPanel, 'numeric');
            app.ens_signalzthreshEditField = uieditfield(app.newensembleparamsPanel, 'numeric');
            app.ens_extractionthreshDropDown = uidropdown(app.newensembleparamsPanel);
            app.ens_extractionthreshDropDown.Items = {};
            app.ens_extractionthreshDropDown.Value = {};
            app.ens_ensextractionDropDown = uidropdown(app.newensembleparamsPanel);
            app.ens_ensextractionDropDown.Items = {};
            app.ens_ensextractionDropDown.Value = {};
            app.ens_normalizeDropDown = uidropdown(app.newensembleparamsPanel);
            app.ens_normalizeDropDown.Items = {};
            app.ens_normalizeDropDown.Value = {};
            app.ens_ensemethodDropDown = uidropdown(app.newensembleparamsPanel);
            app.ens_ensemethodDropDown.Items = {};
            app.ens_ensemethodDropDown.Value = {};
            app.extrasmoothSDEditFieldLabel = uilabel(app.newensembleparamsPanel);
            app.extrasmoothSDEditFieldLabel.Text = 'extra smooth SD';
            app.ens_extrasmoothSDEditField = uieditfield(app.newensembleparamsPanel, 'numeric');
            app.ens_extrasmoothSDEditField.Limits = [0 Inf];
            app.ensembleanalysisButton = uibutton(app.ensfindTab, 'push');
            app.ensembleanalysisButton.ButtonPushedFcn = createCallbackFcn(app, @ensembleanalysisButtonPushed, true);
            app.ensembleanalysisButton.Text = 'ensemble analysis';
            app.numenstofindvalEditFieldLabel = uilabel(app.ensfindTab);
            app.numenstofindvalEditFieldLabel.Text = 'num ens to find val';
            app.numenstofindvalEditField = uieditfield(app.ensfindTab, 'numeric');
            app.numenstofindvalEditField.Value = 10;
            app.numsigensEditFieldLabel = uilabel(app.ensfindTab);
            app.numsigensEditFieldLabel.Text = 'num sig ens';
            app.numsigensEditField = uieditfield(app.ensfindTab, 'numeric');
            app.numsigensEditField.Editable = 'off';
            app.ensemblestatsButton = uibutton(app.ensfindTab, 'push');
            app.ensemblestatsButton.ButtonPushedFcn = createCallbackFcn(app, @ensemblestatsButtonPushed, true);
            app.ensemblestatsButton.Text = 'ensemble stats';
            app.ensembleplotButton = uibutton(app.ensfindTab, 'push');
            app.ensembleplotButton.ButtonPushedFcn = createCallbackFcn(app, @ensembleplotButtonPushed, true);
            app.ensembleplotButton.Text = 'ensemble plot';
            app.shuffrepsEditFieldLabel = uilabel(app.ensfindTab);
            app.shuffrepsEditFieldLabel.Text = 'shuff reps';
            app.ensshuffrepsEditField = uieditfield(app.ensfindTab, 'numeric');
            app.ensembleplot2Button = uibutton(app.ensfindTab, 'push');
            app.ensembleplot2Button.ButtonPushedFcn = createCallbackFcn(app, @ensembleplot2ButtonPushed, true);
            app.ensembleplot2Button.Text = 'ensemble plot 2';
            app.numenstofindmethodDropDownLabel = uilabel(app.ensfindTab);
            app.numenstofindmethodDropDownLabel.Text = 'num ens to find method';
            app.numenstofindmethodDropDown = uidropdown(app.ensfindTab);
            app.numenstofindmethodDropDown.Items = {'Value', 'Corr fraction', ''};
            app.numenstofindmethodDropDown.Value = 'Corr fraction';
            app.numenstofindfracEditFieldLabel = uilabel(app.ensfindTab);
            app.numenstofindfracEditFieldLabel.Text = 'num ens to find frac';
            app.numenstofindfracEditField = uieditfield(app.ensfindTab, 'numeric');
            app.numenstofindfracEditField.Value = 2;
            app.ensonlysignificantCheckBox = uicheckbox(app.ensfindTab);
            app.ensonlysignificantCheckBox.Text = 'only significant';
            app.ensplotsTab = uitab(app.TabGroup);
            app.ensplotsTab.Title = 'ens plots';
            app.plotrasterButton = uibutton(app.ensplotsTab, 'push');
            app.plotrasterButton.ButtonPushedFcn = createCallbackFcn(app, @plotrasterButtonPushed, true);
            app.plotrasterButton.Text = 'plot raster';
            app.shufflecellsCheckBox = uicheckbox(app.ensplotsTab);
            app.shufflecellsCheckBox.Text = 'shuffle cells';
            app.shufflecellsCheckBox.Value = true;
            app.shuffletrialsCheckBox = uicheckbox(app.ensplotsTab);
            app.shuffletrialsCheckBox.Text = 'shuffle trials';
            app.sortbycellsimilarityCheckBox = uicheckbox(app.ensplotsTab);
            app.sortbycellsimilarityCheckBox.Text = 'sort by cell similarity';
            app.sortbycellsimilarityCheckBox.Value = true;
            app.sortbytrialtypeCheckBox = uicheckbox(app.ensplotsTab);
            app.sortbytrialtypeCheckBox.Text = 'sort by trial type';
            app.sortbytrialtypeCheckBox.Value = true;
            app.plotsortingstuffCheckBox = uicheckbox(app.ensplotsTab);
            app.plotsortingstuffCheckBox.Text = 'plot sorting stuff';
            app.plotenscountsButton = uibutton(app.ensplotsTab, 'push');
            app.plotenscountsButton.ButtonPushedFcn = createCallbackFcn(app, @plotenscountsButtonPushed, true);
            app.plotenscountsButton.Text = 'plot ens counts';
            app.plotensdeetsButton = uibutton(app.ensplotsTab, 'push');
            app.plotensdeetsButton.ButtonPushedFcn = createCallbackFcn(app, @plotensdeetsButtonPushed, true);
            app.plotensdeetsButton.Text = 'plot ens deets';
            app.plotscoresrasterButton = uibutton(app.ensplotsTab, 'push');
            app.plotscoresrasterButton.ButtonPushedFcn = createCallbackFcn(app, @plotscoresrasterButtonPushed, true);
            app.plotscoresrasterButton.Text = 'plot scores raster';
            app.SortenstrialsCheckBox = uicheckbox(app.ensplotsTab);
            app.SortenstrialsCheckBox.Text = 'Sort ens trials';
            app.plotensraster2Button = uibutton(app.ensplotsTab, 'push');
            app.plotensraster2Button.ButtonPushedFcn = createCallbackFcn(app, @plotensraster2ButtonPushed, true);
            app.plotensraster2Button.Text = 'plot ens raster2';
            app.enslesssingletrialButton = uibutton(app.ensplotsTab, 'push');
            app.enslesssingletrialButton.ButtonPushedFcn = createCallbackFcn(app, @enslesssingletrialButtonPushed, true);
            app.enslesssingletrialButton.Text = 'ensless single trial';
            app.selectrespcellsCheckBox = uicheckbox(app.ensplotsTab);
            app.selectrespcellsCheckBox.Text = 'select resp cells';
            app.selectrespcellsCheckBox.Value = true;
            app.resortbyensCheckBox = uicheckbox(app.ensplotsTab);
            app.resortbyensCheckBox.Text = 'resort by ens';
            app.sorttrialsCheckBox = uicheckbox(app.ensplotsTab);
            app.sorttrialsCheckBox.Text = 'sort trials';
            app.sortwithfullfrCheckBox = uicheckbox(app.ensplotsTab);
            app.sortwithfullfrCheckBox.Text = 'sort with full fr';
            app.sortwithfullfrCheckBox.Value = true;
            app.EnslesstrialdimButton = uibutton(app.ensplotsTab, 'push');
            app.EnslesstrialdimButton.ButtonPushedFcn = createCallbackFcn(app, @EnslesstrialdimButtonPushed, true);
            app.EnslesstrialdimButton.Text = 'Ensless trial dim';
            app.PlotindivtrialsCheckBox = uicheckbox(app.ensplotsTab);
            app.PlotindivtrialsCheckBox.Text = 'Plot indiv trials';
            app.ShufletrorderinsteadCheckBox = uicheckbox(app.ensplotsTab);
            app.ShufletrorderinsteadCheckBox.Text = 'Shufle tr order instead';
            app.ShufletrorderinsteadCheckBox.Value = true;
            app.SelectcellsDropDownLabel = uilabel(app.ensplotsTab);
            app.SelectcellsDropDownLabel.Text = 'Select cells';
            app.SelectcellsDropDown = uidropdown(app.ensplotsTab);
            app.SelectcellsDropDown.Items = {'Trial responsive', 'Ensemble', 'Both', 'All cells'};
            app.SelectcellsDropDown.Value = 'Trial responsive';
            app.PlotcorrtotalsCheckBox = uicheckbox(app.ensplotsTab);
            app.PlotcorrtotalsCheckBox.Text = 'Plot corr totals';
            app.PlotcombinedtrialsCheckBox = uicheckbox(app.ensplotsTab);
            app.PlotcombinedtrialsCheckBox.Text = 'Plot combined trials';
            app.PlotrespensCheckBox = uicheckbox(app.ensplotsTab);
            app.PlotrespensCheckBox.Text = 'Plot resp ens';
            app.HclusterCheckBox = uicheckbox(app.ensplotsTab);
            app.HclusterCheckBox.Text = 'Hcluster';
            app.NumclustEditFieldLabel = uilabel(app.ensplotsTab);
            app.NumclustEditFieldLabel.Text = 'Num clust';
            app.NumclustEditField = uieditfield(app.ensplotsTab, 'numeric');
            app.NumclustEditField.Value = 1;
            app.plotclusttriggeredstuffCheckBox = uicheckbox(app.ensplotsTab);
            app.plotclusttriggeredstuffCheckBox.Text = 'plot clust triggered stuff';
            app.ClustertouseEditFieldLabel = uilabel(app.ensplotsTab);
            app.ClustertouseEditFieldLabel.Text = 'Cluster to use';
            app.ClustertouseEditField = uieditfield(app.ensplotsTab, 'numeric');
            app.ClustertouseEditField.Value = 1;
            app.clusttriggeredwhatDropDownLabel = uilabel(app.ensplotsTab);
            app.clusttriggeredwhatDropDownLabel.Text = 'clust triggered what';
            app.clusttriggeredwhatDropDown = uidropdown(app.ensplotsTab);
            app.clusttriggeredwhatDropDown.Items = {'stim', 'ensembles', 'cells'};
            app.clusttriggeredwhatDropDown.Value = 'stim';
            app.plotensrasterButton = uibutton(app.ensplotsTab, 'push');
            app.plotensrasterButton.ButtonPushedFcn = createCallbackFcn(app, @plotensrasterButtonPushed, true);
            app.plotensrasterButton.Text = 'plot ens raster';
            app.Enslesssingletrial2Button = uibutton(app.ensplotsTab, 'push');
            app.Enslesssingletrial2Button.ButtonPushedFcn = createCallbackFcn(app, @Enslesssingletrial2ButtonPushed, true);
            app.Enslesssingletrial2Button.Text = 'Ensless single trial2';
            app.enslesscorrButton = uibutton(app.ensplotsTab, 'push');
            app.enslesscorrButton.ButtonPushedFcn = createCallbackFcn(app, @enslesscorrButtonPushed, true);
            app.enslesscorrButton.Text = 'ensless corr';

            app.enslesscorr2Button = uibutton(app.ensplotsTab, 'push');
            app.enslesscorr2Button.ButtonPushedFcn = createCallbackFcn(app, @enslesscorr2ButtonPushed, true);
            app.enslesscorr2Button.Text = 'ensless corr2';
            app.enslesscorrsampcellsButton = uibutton(app.ensplotsTab, 'push');
            app.enslesscorrsampcellsButton.ButtonPushedFcn = createCallbackFcn(app, @enslesscorrsampcellsButtonPushed, true);
            app.enslesscorrsampcellsButton.Text = 'ensless corr samp cells';
            app.decoderonevallButton = uibutton(app.ensplotsTab, 'push');
            app.decoderonevallButton.ButtonPushedFcn = createCallbackFcn(app, @decoderonevallButtonPushed, true);
            app.decoderonevallButton.Text = 'decoder one v all';
            app.SorttypeDropDownLabel = uilabel(app.ensplotsTab);
            app.SorttypeDropDownLabel.Text = 'Sort type';
            app.SorttypeDropDown = uidropdown(app.ensplotsTab);
            app.SorttypeDropDown.Items = {};
            app.SorttypeDropDown.Value = {};
            app.CorrtypeDropDownLabel = uilabel(app.ensplotsTab);
            app.CorrtypeDropDownLabel.Text = 'Corr type';
            app.CorrtypeDropDown = uidropdown(app.ensplotsTab);
            app.CorrtypeDropDown.Items = {};
            app.CorrtypeDropDown.Value = {};
            app.EqualizetrialsampsizeCheckBox = uicheckbox(app.ensplotsTab);
            app.EqualizetrialsampsizeCheckBox.Text = 'Equalize trial samp size';
            app.EqualizetrialsampsizeCheckBox.Value = true;
            app.plotpopvecCheckBox = uicheckbox(app.ensplotsTab);
            app.plotpopvecCheckBox.Text = 'plot pop vec';
            app.sortcellbytraceaveCheckBox = uicheckbox(app.ensplotsTab);
            app.sortcellbytraceaveCheckBox.Text = 'sort cell by trace ave';
            app.sortprevtrialCheckBox = uicheckbox(app.ensplotsTab);
            app.sortprevtrialCheckBox.Text = 'sort prev trial';
            app.plotprevtrialCheckBox = uicheckbox(app.ensplotsTab);
            app.plotprevtrialCheckBox.Text = 'plot prev trial';
            app.samprangeminEditFieldLabel = uilabel(app.ensplotsTab);
            app.samprangeminEditFieldLabel.Text = 'samp range min';
            app.samprangeminEditField = uieditfield(app.ensplotsTab, 'numeric');
            app.samprangeminEditField.Value = 4;
            app.samprangemaxEditFieldLabel = uilabel(app.ensplotsTab);
            app.samprangemaxEditFieldLabel.Text = 'samp range max';
            app.samprangemaxEditField = uieditfield(app.ensplotsTab, 'numeric');
            app.samprangemaxEditField.Value = 50;
            app.samprangeintervEditFieldLabel = uilabel(app.ensplotsTab);
            app.samprangeintervEditFieldLabel.Text = 'samp range interv';
            app.samprangeintervEditField = uieditfield(app.ensplotsTab, 'numeric');
            app.samprangeintervEditField.Value = 3;
            app.sampnumEditFieldLabel = uilabel(app.ensplotsTab);
            app.sampnumEditFieldLabel.Text = 'samp num';
            app.sampnumEditField = uieditfield(app.ensplotsTab, 'numeric');
            app.sampnumEditField.Value = 10;
            app.decoderonevoneButton = uibutton(app.ensplotsTab, 'push');
            app.decoderonevoneButton.ButtonPushedFcn = createCallbackFcn(app, @decoderonevoneButtonPushed, true);
            app.decoderonevoneButton.Text = 'decoder one v one';
            app.similarityonevoneButton = uibutton(app.ensplotsTab, 'push');
            app.similarityonevoneButton.ButtonPushedFcn = createCallbackFcn(app, @similarityonevoneButtonPushed, true);
            app.similarityonevoneButton.Text = 'similarity one v one';
            app.ensplots2Tab = uitab(app.TabGroup);
            app.ensplots2Tab.Title = 'ens plots2';
            app.lowdimprojcellButton = uibutton(app.ensplots2Tab, 'push');
            app.lowdimprojcellButton.ButtonPushedFcn = createCallbackFcn(app, @lowdimprojcellButtonPushed, true);
            app.lowdimprojcellButton.Text = 'low dim proj cell';
            app.lowdimprojtrialButton = uibutton(app.ensplots2Tab, 'push');
            app.lowdimprojtrialButton.ButtonPushedFcn = createCallbackFcn(app, @lowdimprojtrialButtonPushed, true);
            app.lowdimprojtrialButton.Text = 'low dim proj trial';
            app.isomap2Button = uibutton(app.ensplots2Tab, 'push');
            app.isomap2Button.ButtonPushedFcn = createCallbackFcn(app, @isomap2ButtonPushed, true);
            app.isomap2Button.Text = 'isomap2';
            app.lowdimprojtrialctxButton = uibutton(app.ensplots2Tab, 'push');
            app.lowdimprojtrialctxButton.ButtonPushedFcn = createCallbackFcn(app, @lowdimprojtrialctxButtonPushed, true);
            app.lowdimprojtrialctxButton.Text = 'low dim proj trial ctx';
            app.lowdimprojfulldataButton = uibutton(app.ensplots2Tab, 'push');
            app.lowdimprojfulldataButton.ButtonPushedFcn = createCallbackFcn(app, @lowdimprojfulldataButtonPushed, true);
            app.lowdimprojfulldataButton.Text = 'low dim proj full data';
            app.LDcontpadEditFieldLabel = uilabel(app.ensplots2Tab);
            app.LDcontpadEditFieldLabel.Text = 'LD cont pad';
            app.LDcontpadEditField = uieditfield(app.ensplots2Tab, 'numeric');
            app.LDcontpadEditField.Limits = [0 10];
            app.LDcontpadEditField.RoundFractionalValues = 'on';
            app.LDcontpadEditField.Value = 1;
            app.lowdimprojcellctxButton = uibutton(app.ensplots2Tab, 'push');
            app.lowdimprojcellctxButton.ButtonPushedFcn = createCallbackFcn(app, @lowdimprojcellctxButtonPushed, true);
            app.lowdimprojcellctxButton.Text = 'low dim proj cell ctx';
            app.dsetplotindivtrialsButton = uibutton(app.ensplots2Tab, 'push');
            app.dsetplotindivtrialsButton.ButtonPushedFcn = createCallbackFcn(app, @dsetplotindivtrialsButtonPushed, true);
            app.dsetplotindivtrialsButton.Text = 'dset plot indiv trials';
            app.dimesttrialshuffButton = uibutton(app.ensplots2Tab, 'push');
            app.dimesttrialshuffButton.ButtonPushedFcn = createCallbackFcn(app, @dimesttrialshuffButtonPushed, true);
            app.dimesttrialshuffButton.Text = 'dim est trial shuff';
            app.lowdimprojtrial2Button = uibutton(app.ensplots2Tab, 'push');
            app.lowdimprojtrial2Button.ButtonPushedFcn = createCallbackFcn(app, @lowdimprojtrial2ButtonPushed, true);
            app.lowdimprojtrial2Button.Text = 'low dim proj trial 2';
            app.lowdimprojcell2Button = uibutton(app.ensplots2Tab, 'push');
            app.lowdimprojcell2Button.ButtonPushedFcn = createCallbackFcn(app, @lowdimprojcell2ButtonPushed, true);
            app.lowdimprojcell2Button.Text = 'low dim proj cell 2';
            app.distancesButton = uibutton(app.ensplots2Tab, 'push');
            app.distancesButton.ButtonPushedFcn = createCallbackFcn(app, @distancesButtonPushed, true);
            app.distancesButton.Text = 'distances';
            app.subtractmeanCheckBox = uicheckbox(app.ensplots2Tab);
            app.subtractmeanCheckBox.Text = 'subtract mean';
            app.plotsubmeanCheckBox = uicheckbox(app.ensplots2Tab);
            app.plotsubmeanCheckBox.Text = 'plot sub mean';
            app.scalebyvarCheckBox = uicheckbox(app.ensplots2Tab);
            app.scalebyvarCheckBox.Text = 'scale by var';
            app.numcompplotSpinnerLabel = uilabel(app.ensplots2Tab);
            app.numcompplotSpinnerLabel.Text = 'num comp plot';
            app.numcompplotSpinner = uispinner(app.ensplots2Tab);
            app.numcompplotSpinner.Limits = [1 Inf];
            app.numcompplotSpinner.RoundFractionalValues = 'on';
            app.numcompplotSpinner.Value = 2;
            app.equalizeaxesCheckBox = uicheckbox(app.ensplots2Tab);
            app.equalizeaxesCheckBox.Text = 'equalize axes';
            app.plotaxesCheckBox = uicheckbox(app.ensplots2Tab);
            app.plotaxesCheckBox.Text = 'plot axes';
            app.projectsingletrialsCheckBox = uicheckbox(app.ensplots2Tab);
            app.projectsingletrialsCheckBox.Text = 'project single trials';
            app.NumplotaxesDropDownLabel = uilabel(app.ensplots2Tab);
            app.NumplotaxesDropDownLabel.Text = 'Num plot axes';
            app.NumplotaxesDropDown = uidropdown(app.ensplots2Tab);
            app.NumplotaxesDropDown.Items = {};
            app.NumplotaxesDropDown.Value = {};
            app.FlipshadowXCheckBox = uicheckbox(app.ensplots2Tab);
            app.FlipshadowXCheckBox.Text = 'Flip shadow X';
            app.FlipshadowYCheckBox = uicheckbox(app.ensplots2Tab);
            app.FlipshadowYCheckBox.Text = 'Flip shadow Y';
            app.FlipshadowZCheckBox = uicheckbox(app.ensplots2Tab);
            app.FlipshadowZCheckBox.Text = 'Flip shadow Z';
            app.ReverseXCheckBox = uicheckbox(app.ensplots2Tab);
            app.ReverseXCheckBox.Text = 'Reverse X';
            app.ReverseYCheckBox = uicheckbox(app.ensplots2Tab);
            app.ReverseYCheckBox.Text = 'Reverse Y';
            app.ReverseZCheckBox = uicheckbox(app.ensplots2Tab);
            app.ReverseZCheckBox.Text = 'Reverse Z';
            app.roiregTab = uitab(app.TabGroup);
            app.roiregTab.Title = 'roi reg';
            app.UIAxes_roi = uiaxes(app.roiregTab);
            title(app.UIAxes_roi, 'ROI')
            zlabel(app.UIAxes_roi, 'Z')
            app.registerroisButton = uibutton(app.roiregTab, 'push');
            app.registerroisButton.ButtonPushedFcn = createCallbackFcn(app, @registerroisButtonPushed, true);
            app.registerroisButton.Text = 'register rois';
            app.SNRcaimanEditFieldLabel = uilabel(app.roiregTab);
            app.SNRcaimanEditFieldLabel.Text = 'SNR caiman';
            app.SNRcaimanEditField = uieditfield(app.roiregTab, 'numeric');
            app.SNRcaimanEditField.Editable = 'off';
            app.SNR2EditFieldLabel = uilabel(app.roiregTab);
            app.SNR2EditFieldLabel.Text = 'SNR2';
            app.SNR2EditField = uieditfield(app.roiregTab, 'numeric');
            app.SNR2EditField.Editable = 'off';
            app.CNNprobEditFieldLabel = uilabel(app.roiregTab);
            app.CNNprobEditFieldLabel.Text = 'CNN prob';
            app.CNNprobEditField = uieditfield(app.roiregTab, 'numeric');
            app.CNNprobEditField.Editable = 'off';
            app.RvalueEditFieldLabel = uilabel(app.roiregTab);
            app.RvalueEditFieldLabel.Text = 'Rvalue';
            app.RvalueEditField = uieditfield(app.roiregTab, 'numeric');
            app.RvalueEditField.Editable = 'off';
            app.plotregroisButton = uibutton(app.roiregTab, 'push');
            app.plotregroisButton.ButtonPushedFcn = createCallbackFcn(app, @plotregroisButtonPushed, true);
            app.plotregroisButton.Text = 'plot reg rois';
            app.loadcaimanroiregistractionButton = uibutton(app.roiregTab, 'push');
            app.loadcaimanroiregistractionButton.ButtonPushedFcn = createCallbackFcn(app, @loadcaimanroiregistractionButtonPushed, true);
            app.loadcaimanroiregistractionButton.Text = 'load caiman roi registraction';
            app.regdatatouseDropDownLabel = uilabel(app.roiregTab);
            app.regdatatouseDropDownLabel.Text = 'reg data to use';
            app.regdatatouseDropDown = uidropdown(app.roiregTab);
            app.regdatatouseDropDown.Items = {};
            app.regdatatouseDropDown.Value = {};
            app.regscoretreshEditFieldLabel = uilabel(app.roiregTab);
            app.regscoretreshEditFieldLabel.Text = 'reg score tresh';
            app.regscoretreshEditField = uieditfield(app.roiregTab, 'numeric');
            app.regscoretreshEditField.Limits = [0 1];
            app.regscoretreshEditField.Value = 0.5;
            app.NumdsetstoplotEditFieldLabel = uilabel(app.roiregTab);
            app.NumdsetstoplotEditFieldLabel.Text = 'Num dsets to plot';
            app.NumdsetstoplotEditField = uieditfield(app.roiregTab, 'text');
            app.tuningacrossdsetsButton = uibutton(app.roiregTab, 'push');
            app.tuningacrossdsetsButton.ButtonPushedFcn = createCallbackFcn(app, @tuningacrossdsetsButtonPushed, true);
            app.tuningacrossdsetsButton.Text = 'tuning across dsets';
            app.MMNacrossdsetsButton = uibutton(app.roiregTab, 'push');
            app.MMNacrossdsetsButton.ButtonPushedFcn = createCallbackFcn(app, @MMNacrossdsetsButtonPushed, true);
            app.MMNacrossdsetsButton.Text = 'MMN across dsets';
            app.spontTab = uitab(app.TabGroup);
            app.spontTab.Title = 'spont';
            app.plotrasterspontButton = uibutton(app.spontTab, 'push');
            app.plotrasterspontButton.ButtonPushedFcn = createCallbackFcn(app, @plotrasterspontButtonPushed, true);
            app.plotrasterspontButton.Text = 'plot raster spont';
            app.AddoptocellsButton = uibutton(app.spontTab, 'push');
            app.AddoptocellsButton.ButtonPushedFcn = createCallbackFcn(app, @AddoptocellsButtonPushed, true);
            app.AddoptocellsButton.Text = 'Add opto cells';
            app.plotoptotuningButton = uibutton(app.spontTab, 'push');
            app.plotoptotuningButton.ButtonPushedFcn = createCallbackFcn(app, @plotoptotuningButtonPushed, true);
            app.plotoptotuningButton.Text = 'plot opto tuning';
            app.plotoptospontcorrButton = uibutton(app.spontTab, 'push');
            app.plotoptospontcorrButton.ButtonPushedFcn = createCallbackFcn(app, @plotoptospontcorrButtonPushed, true);
            app.plotoptospontcorrButton.Text = 'plot opto spont corr';
            app.plotoptosignalcorrButton = uibutton(app.spontTab, 'push');
            app.plotoptosignalcorrButton.ButtonPushedFcn = createCallbackFcn(app, @plotoptosignalcorrButtonPushed, true);
            app.plotoptosignalcorrButton.Text = 'plot opto signal corr';
            app.plotoptoresponseButton = uibutton(app.spontTab, 'push');
            app.plotoptoresponseButton.ButtonPushedFcn = createCallbackFcn(app, @plotoptoresponseButtonPushed, true);
            app.plotoptoresponseButton.Text = 'plot opto response';
            app.optodataTab = uitab(app.TabGroup);
            app.optodataTab.Title = 'opto data';
            app.PlottuningframeButton = uibutton(app.optodataTab, 'push');
            app.PlottuningframeButton.ButtonPushedFcn = createCallbackFcn(app, @PlottuningframeButtonPushed, true);
            app.PlottuningframeButton.Text = 'Plot tuning frame';
            app.PlottuningtypeDropDownLabel = uilabel(app.optodataTab);
            app.PlottuningtypeDropDownLabel.Text = 'Plot tuning type';
            app.PlottuningtypeDropDown = uidropdown(app.optodataTab);
            app.PlottuningtypeDropDown.Items = {};
            app.PlottuningtypeDropDown.Value = {};
            app.ScalewithmagCheckBox = uicheckbox(app.optodataTab);
            app.ScalewithmagCheckBox.Text = 'Scale with mag';
            app.plottuningthreshEditFieldLabel = uilabel(app.optodataTab);
            app.plottuningthreshEditFieldLabel.Text = 'plot tuning thresh';
            app.plottuningthreshEditField = uieditfield(app.optodataTab, 'numeric');
            app.computetuningsimilarityButton = uibutton(app.optodataTab, 'push');
            app.computetuningsimilarityButton.ButtonPushedFcn = createCallbackFcn(app, @computetuningsimilarityButtonPushed, true);
            app.computetuningsimilarityButton.Text = 'compute tuning similarity';
            app.PlottuningframewithinButton = uibutton(app.optodataTab, 'push');
            app.PlottuningframewithinButton.ButtonPushedFcn = createCallbackFcn(app, @PlottuningframewithinButtonPushed, true);
            app.PlottuningframewithinButton.Text = 'Plot tuning frame within';
            app.PlotlickresponseButton = uibutton(app.optodataTab, 'push');
            app.PlotlickresponseButton.ButtonPushedFcn = createCallbackFcn(app, @PlotlickresponseButtonPushed, true);
            app.PlotlickresponseButton.Text = 'Plot lick response';
            app.licktuningsimilarityButton = uibutton(app.optodataTab, 'push');
            app.licktuningsimilarityButton.ButtonPushedFcn = createCallbackFcn(app, @licktuningsimilarityButtonPushed, true);
            app.licktuningsimilarityButton.Text = 'lick tuning similarity';
            app.TFwithinzthreshEditFieldLabel = uilabel(app.optodataTab);
            app.TFwithinzthreshEditFieldLabel.Text = 'TF within z thresh';
            app.TFwithinzthreshEditField = uieditfield(app.optodataTab, 'numeric');
            app.TFwithinzthreshEditField.Value = 4;
            app.RunallButton = uibutton(app.UIFigure, 'push');
            app.RunallButton.ButtonPushedFcn = createCallbackFcn(app, @RunallButtonPushed, true);
            app.RunallButton.Text = 'Run all';
            app.RunallDropDownLabel = uilabel(app.UIFigure);
            app.RunallDropDownLabel.Text = 'Run all';
            app.RunallDropDown = uidropdown(app.UIFigure);
            app.RunallDropDown.Items = {};
            app.RunallDropDown.Value = {};
            app.matdatapathEditFieldLabel = uilabel(app.UIFigure);
            app.matdatapathEditFieldLabel.Text = 'mat data path';
            app.matdatapathEditField = uieditfield(app.UIFigure, 'text');
            app.LoadmatdataButton = uibutton(app.UIFigure, 'push');
            app.LoadmatdataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadmatdataButtonPushed, true);
            app.LoadmatdataButton.Text = 'Load mat data';
            app.SavematdataButton = uibutton(app.UIFigure, 'push');
            app.SavematdataButton.ButtonPushedFcn = createCallbackFcn(app, @SavematdataButtonPushed, true);
            app.SavematdataButton.Text = 'Save mat data';
            app.anchordsetSpinnerLabel = uilabel(app.UIFigure);
            app.anchordsetSpinnerLabel.Text = 'anchor dset';
            app.anchordsetSpinner = uispinner(app.UIFigure);
            app.anchordsetSpinner.Limits = [1 Inf];
            app.anchordsetSpinner.Value = 1;
            app.OverwriteCheckBox = uicheckbox(app.UIFigure);
            app.OverwriteCheckBox.Text = 'Overwrite';
            app.ViewparamsButton = uibutton(app.UIFigure, 'push');
            app.ViewparamsButton.ButtonPushedFcn = createCallbackFcn(app, @ViewparamsButtonPushed, true);
            app.ViewparamsButton.Text = 'View params';
            app.ExperimentDropDownLabel = uilabel(app.UIFigure);
            app.ExperimentDropDownLabel.Text = 'Experiment';
            app.ExperimentDropDown = uidropdown(app.UIFigure);
            app.ExperimentDropDown.Items = {};
            app.ExperimentDropDown.Value = {};
            app.RawButton = uibutton(app.UIFigure, 'state');
            app.RawButton.ValueChangedFcn = createCallbackFcn(app, @RawButtonValueChanged, true);
            app.RawButton.Text = 'Raw';
            app.RawButton.Value = true;
            app.CButton = uibutton(app.UIFigure, 'state');
            app.CButton.ValueChangedFcn = createCallbackFcn(app, @CButtonValueChanged, true);
            app.CButton.Text = 'C';
            app.SpikesButton = uibutton(app.UIFigure, 'state');
            app.SpikesButton.ValueChangedFcn = createCallbackFcn(app, @SpikesButtonValueChanged, true);
            app.SpikesButton.Text = 'Spikes';
            app.trialtypeDropDownLabel = uilabel(app.UIFigure);
            app.trialtypeDropDownLabel.Text = 'trial type';
            app.trialtypeDropDown = uidropdown(app.UIFigure);
            app.trialtypeDropDown.Items = {};
            app.trialtypeDropDown.ValueChangedFcn = createCallbackFcn(app, @trialtypeDropDownValueChanged, true);
            app.trialtypeDropDown.Value = {};
            app.StimtimesButton = uibutton(app.UIFigure, 'state');
            app.StimtimesButton.ValueChangedFcn = createCallbackFcn(app, @StimtimesButtonValueChanged, true);
            app.StimtimesButton.Text = 'Stim times';
            app.SelectdatagroupDropDownLabel = uilabel(app.UIFigure);
            app.SelectdatagroupDropDownLabel.Text = 'Select data group';
            app.SelectdatagroupDropDown = uidropdown(app.UIFigure);
            app.SelectdatagroupDropDown.Items = {};
            app.SelectdatagroupDropDown.Value = {};
            app.ContoursDropDownLabel = uilabel(app.UIFigure);
            app.ContoursDropDownLabel.Text = 'Contours';
            app.ContoursDropDown = uidropdown(app.UIFigure);
            app.ContoursDropDown.Items = {};
            app.ContoursDropDown.ValueChangedFcn = createCallbackFcn(app, @ContoursDropDownValueChanged, true);
            app.ContoursDropDown.Value = {};
            app.ResposivecellstypeDropDownLabel = uilabel(app.UIFigure);
            app.ResposivecellstypeDropDownLabel.Text = 'Resposive cells type';
            app.ResposivecellstypeDropDown = uidropdown(app.UIFigure);
            app.ResposivecellstypeDropDown.Items = {};
            app.ResposivecellstypeDropDown.Value = {};
            app.CloseallButton = uibutton(app.UIFigure, 'push');
            app.CloseallButton.ButtonPushedFcn = createCallbackFcn(app, @CloseallButtonPushed, true);
            app.CloseallButton.Text = 'Close all';
            app.ResponsivecellsselectDropDownLabel = uilabel(app.UIFigure);
            app.ResponsivecellsselectDropDownLabel.Text = 'Responsive cells select';
            app.ResponsivecellsselectDropDown = uidropdown(app.UIFigure);
            app.ResponsivecellsselectDropDown.Items = {};
            app.ResponsivecellsselectDropDown.Value = {};
            app.RespthreshEditFieldLabel = uilabel(app.UIFigure);
            app.RespthreshEditFieldLabel.Text = 'Resp thresh';
            app.RespthreshEditField = uieditfield(app.UIFigure, 'numeric');
            app.RespthreshEditField.Value = 1;
            app.UpdateButton = uibutton(app.UIFigure, 'push');
            app.UpdateButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateButtonPushed, true);
            app.UpdateButton.Text = 'Update';
            app.plotcolorlegendsButton = uibutton(app.UIFigure, 'push');
            app.plotcolorlegendsButton.ButtonPushedFcn = createCallbackFcn(app, @plotcolorlegendsButtonPushed, true);
            app.plotcolorlegendsButton.Text = 'plot color legends';
            app.regiontoplotDropDownLabel = uilabel(app.UIFigure);
            app.regiontoplotDropDownLabel.Text = 'region to plot';
            app.regiontoplotDropDown = uidropdown(app.UIFigure);
            app.regiontoplotDropDown.Items = {};
            app.regiontoplotDropDown.Value = {};
            app.automatCheckBox = uicheckbox(app.UIFigure);
            app.automatCheckBox.Text = 'auto mat';
            app.automatCheckBox.Value = true;
            app.autoregCheckBox = uicheckbox(app.UIFigure);
            app.autoregCheckBox.Text = 'auto reg';
            app.autoregCheckBox.Value = true;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = dset_viewer

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end